classdef Tigerv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OversightTabGroup               matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        PickTableButton                 matlab.ui.control.Button
        PickFilesButton                 matlab.ui.control.Button
        resetValuesButton               matlab.ui.control.Button
        resetMapButton                  matlab.ui.control.Button
        SelectDirectoryButton           matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        DirectoryLabel                  matlab.ui.control.Label
        importMapButton                 matlab.ui.control.Button
        FlagunflagallElectrodesCheckBox  matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_22        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_21        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_20        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_19        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_18        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_17        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_16        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_15        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_14        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_13        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_12        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_11        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_10        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_9         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_8         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_7         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_6         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_5         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_4         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_3         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_2         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_1         matlab.ui.control.CheckBox
        FlaggedLabel                    matlab.ui.control.Label
        ComfortLabel                    matlab.ui.control.Label
        ThresholdLabel                  matlab.ui.control.Label
        Comfort_22                      matlab.ui.control.NumericEditField
        Comfort_21                      matlab.ui.control.NumericEditField
        Comfort_20                      matlab.ui.control.NumericEditField
        Comfort_19                      matlab.ui.control.NumericEditField
        Comfort_18                      matlab.ui.control.NumericEditField
        Comfort_17                      matlab.ui.control.NumericEditField
        Comfort_16                      matlab.ui.control.NumericEditField
        Comfort_15                      matlab.ui.control.NumericEditField
        Comfort_14                      matlab.ui.control.NumericEditField
        Comfort_13                      matlab.ui.control.NumericEditField
        Comfort_12                      matlab.ui.control.NumericEditField
        Comfort_11                      matlab.ui.control.NumericEditField
        Comfort_10                      matlab.ui.control.NumericEditField
        Comfort_9                       matlab.ui.control.NumericEditField
        Comfort_8                       matlab.ui.control.NumericEditField
        Comfort_7                       matlab.ui.control.NumericEditField
        Comfort_6                       matlab.ui.control.NumericEditField
        Comfort_5                       matlab.ui.control.NumericEditField
        Comfort_4                       matlab.ui.control.NumericEditField
        Comfort_3                       matlab.ui.control.NumericEditField
        Comfort_2                       matlab.ui.control.NumericEditField
        Comfort_1                       matlab.ui.control.NumericEditField
        Threshold_22                    matlab.ui.control.NumericEditField
        Threshold_21                    matlab.ui.control.NumericEditField
        Threshold_20                    matlab.ui.control.NumericEditField
        Threshold_19                    matlab.ui.control.NumericEditField
        Threshold_18                    matlab.ui.control.NumericEditField
        Threshold_17                    matlab.ui.control.NumericEditField
        Threshold_16                    matlab.ui.control.NumericEditField
        Threshold_15                    matlab.ui.control.NumericEditField
        Threshold_14                    matlab.ui.control.NumericEditField
        Threshold_13                    matlab.ui.control.NumericEditField
        Threshold_12                    matlab.ui.control.NumericEditField
        Threshold_11                    matlab.ui.control.NumericEditField
        Threshold_10                    matlab.ui.control.NumericEditField
        Threshold_9                     matlab.ui.control.NumericEditField
        Threshold_8                     matlab.ui.control.NumericEditField
        Threshold_7                     matlab.ui.control.NumericEditField
        Threshold_6                     matlab.ui.control.NumericEditField
        Threshold_5                     matlab.ui.control.NumericEditField
        Threshold_4                     matlab.ui.control.NumericEditField
        Threshold_3                     matlab.ui.control.NumericEditField
        Threshold_2                     matlab.ui.control.NumericEditField
        Threshold_1                     matlab.ui.control.NumericEditField
        ExperimentTabGroup              matlab.ui.container.TabGroup
        TestSequenceTab                 matlab.ui.container.Tab
        TestSeq_PulsesLabel             matlab.ui.control.Label
        Testseq_ResetAmplitudes         matlab.ui.control.Button
        TestSeq_ResetElectrodes         matlab.ui.control.Button
        InterpulsegapmsLabel_3          matlab.ui.control.Label
        TestSeq_Amplitudes              matlab.ui.control.EditField
        InterpulsegapmsLabel_2          matlab.ui.control.Label
        TestSeq_Electrodes              matlab.ui.control.EditField
        TestSeq_Run                     matlab.ui.control.Button
        TestSeq_Filename                matlab.ui.control.Label
        TestSeq_Plot                    matlab.ui.control.UIAxes
        DiscrimationExpTab              matlab.ui.container.Tab
        Discrimination_ExcludeCatchTrialsCheckBox  matlab.ui.control.CheckBox
        Discrimination_SequencePatternListLabel  matlab.ui.control.Label
        Discrimination_ClearTable       matlab.ui.control.Button
        Discrimination_TablenameLabel   matlab.ui.control.Label
        Discrimination_SaveTableButton  matlab.ui.control.Button
        Discrimination_ReturnButton     matlab.ui.control.Button
        Discrimination_Table            matlab.ui.control.Table
        Discrimination_RuntimeLabel     matlab.ui.control.Label
        Discrimination_RunsEditField    matlab.ui.control.EditField
        RunsLabel_2                     matlab.ui.control.Label
        Discrimination_RunButton        matlab.ui.control.Button
        Discrimination_AddfilesButton   matlab.ui.control.Button
        Discrimination_ContinueButton   matlab.ui.control.Button
        Discrimination_ListBox          matlab.ui.control.ListBox
        Discrimination_Plot             matlab.ui.control.UIAxes
        RankingExpTab                   matlab.ui.container.Tab
        Ranking_SequencePatternListLabel  matlab.ui.control.Label
        Ranking_ListBox                 matlab.ui.control.ListBox
        Ranking_ReturnButton            matlab.ui.control.Button
        Ranking_ContinueButton          matlab.ui.control.Button
        Ranking_ExperimentDropDown      matlab.ui.control.DropDown
        Ranking_ExcludeCatchTrialsCheckBox  matlab.ui.control.CheckBox
        Ranking_ClearTable              matlab.ui.control.Button
        Ranking_TablenameLabel          matlab.ui.control.Label
        Ranking_SaveTableButton         matlab.ui.control.Button
        Ranking_RuntimeLabel            matlab.ui.control.Label
        Ranking_Table                   matlab.ui.control.Table
        Ranking_AddfilesButton          matlab.ui.control.Button
        Ranking_RunsEditField           matlab.ui.control.EditField
        RunsLabel                       matlab.ui.control.Label
        Ranking_RunButton               matlab.ui.control.Button
        Ranking_Plot                    matlab.ui.control.UIAxes
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        GenBurstTab                     matlab.ui.container.Tab
        ZoomfactorSpinner               matlab.ui.control.Spinner
        ZoomfactorSpinnerLabel          matlab.ui.control.Label
        IPGInfo_2                       matlab.ui.control.Label
        IPGInfo_1                       matlab.ui.control.Label
        IPGDisplay                      matlab.ui.control.Label
        MaxPulsesLabel                  matlab.ui.control.Label
        RateEditField_inms              matlab.ui.control.NumericEditField
        BurstperiodppsLabel_2           matlab.ui.control.Label
        FrameperiodLabel                matlab.ui.control.Label
        WaitPeriodEditField             matlab.ui.control.NumericEditField
        BurstGapmsLabel                 matlab.ui.control.Label
        InterpulsegapEditField          matlab.ui.control.NumericEditField
        PulsesperburstEditField         matlab.ui.control.NumericEditField
        PulsesperFrameLabel             matlab.ui.control.Label
        InterpulsegapmsLabel            matlab.ui.control.Label
        RateEditField_inpps             matlab.ui.control.NumericEditField
        BurstperiodppsLabel             matlab.ui.control.Label
        InputgapsButton                 matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        SaveAsButton                    matlab.ui.control.Button
        Burst_Example                   matlab.ui.control.UIAxes
        DataAnalysisTab                 matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        DiscriminationPlotTab           matlab.ui.container.Tab
        RankingPlotTab                  matlab.ui.container.Tab
        needautomatedlognamingLabel     matlab.ui.control.Label
        nameofLogorderorsoLabel         matlab.ui.control.Label
        createPlotButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        usuallydirectorylikenormallydisorrankinglogfolderLabel  matlab.ui.control.Label
        FileorFoldertouseEditField      matlab.ui.control.EditField
        FileorFoldertouseEditFieldLabel  matlab.ui.control.Label
        PatientNameEditField            matlab.ui.control.EditField
        PatientNameEditFieldLabel       matlab.ui.control.Label
        giveinputdataButton             matlab.ui.control.Button
        AppSettingsTab                  matlab.ui.container.Tab
        InterpulsegapmsLabel_6          matlab.ui.control.Label
        RampingLength_inmsEditField     matlab.ui.control.NumericEditField
        InterpulsegapmsLabel_5          matlab.ui.control.Label
        Initiate_Amplitudes             matlab.ui.control.NumericEditField
        Initiate_Electrodes             matlab.ui.control.EditField
        InterpulsegapmsLabel_4          matlab.ui.control.Label
        TokenOrderDropDown              matlab.ui.control.DropDown
        TokenOrderDropDownLabel         matlab.ui.control.Label
        TokenPatternDropDown            matlab.ui.control.DropDown
        TokenPatternDropDownLabel       matlab.ui.control.Label
        AllTokenOrdersCheckBox          matlab.ui.control.CheckBox
        BuildframerateLabel             matlab.ui.control.Label
        SequenceGenerationLabel         matlab.ui.control.Label
        OverallLabel                    matlab.ui.control.Label
        GenerateLogCheckBox             matlab.ui.control.CheckBox
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDown_2Label             matlab.ui.control.Label
        Intersequence_inmsEditField     matlab.ui.control.NumericEditField
        IntersequencemsEditFieldLabel   matlab.ui.control.Label
        ClevelsPulsewidthEditField      matlab.ui.control.NumericEditField
        ClevelsPulsewidthEditFieldLabel  matlab.ui.control.Label
        TransfertoNICCheckBox           matlab.ui.control.CheckBox
        Stimduration_inmsEditField      matlab.ui.control.NumericEditField
        StimdurationmsEditFieldLabel    matlab.ui.control.Label
        InterphasegapsEditField         matlab.ui.control.NumericEditField
        InterphasegapsEditFieldLabel    matlab.ui.control.Label
        StimphaseEditField              matlab.ui.control.NumericEditField
        StimphasesEditFieldLabel        matlab.ui.control.Label
        QuestionRepetitionEditField     matlab.ui.control.NumericEditField
        QuestionRepetitionEditFieldLabel  matlab.ui.control.Label
        PitchRankingListExperimentLabel  matlab.ui.control.Label
        Decideafter2unanimousDecisionsCheckBox  matlab.ui.control.CheckBox
        RampingCheckBox                 matlab.ui.control.CheckBox
        CalculateasDynamicRangeofMapCheckBox  matlab.ui.control.CheckBox
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)

        Unified
        TestSeq        % This will hold all Test Sequence-related data 
        IPG
        roundedpossibleFrames

        frame_rate_micros = 64.8; %frame period harcoded to 64.8 - for now hardcoded in μs 
        frame_rate_millis = 64.8/1e3; %frame period harcoded to 64.8 - for now hardcoded in ms 
        possibleFrames % possible Frames for the pps/frame_rate
        fractional_possibleFrames % 0.87 of possibleFrames
        stepsize % basiclly framerate currently
        interpulsegaps % actual gaps + the frame of pulse before 


        ComfortValues double = zeros(1,22); % preallocate Comforts
        ThresholdValues double = zeros(1,22); % preallocate Threshold
        FlagElectrodeCheckBoxValues logical = false(1,22); % preallocate Flags

        PatientID
        Directory
        
        startPath

        % pwd - present working directory
        % startPath = 'C:\'; % main C folder
        brustsfolder = "Burst_structs";
        tablesfolder = "File_Tables"
        
        
        patientFolder
        administrator = {'admin', 'admin'};
        isLoginSuccess = 0; 


        isactiveDynamicRange
        isactiveTransfer
        isactiveRamping
        isactiveLogging

        % UIFigure
        % ... your existing properties ...

        MasterTable table


    end
    
    methods (Access = private)

        function MapValueChanged(app, event, valuesArrayName)
            src = event.Source;
            value = src.Value;
            name = src.Tag;   % e.g. "C7"
        
            idx = ExtractIndexFromTag(app, name);  

            if valuesArrayName == "FlagElectrodeCheckBox"
                app.(valuesArrayName)(idx) = value; % Store true/false
                % Add any specific checkbox logic here (e.g., disabling the row)
                return; 
            end

            app.(valuesArrayName)(idx) = value;   % store value

            % 1. Define the rules once (could even be in your startupFcn)
            rules.ComfortValues   = struct('opposite', "Threshold", 'limit', 2);
            rules.ThresholdValues = struct('opposite', "Comfort",   'limit', 1);
            
            if isfield(rules, valuesArrayName)
                % 2. Look up the settings based on the input name
                config = rules.(valuesArrayName);
                
                % 3. Apply the values
                compName = config.opposite + "_" + idx;
                app.(compName).Limits(config.limit) = value;
            end
        end

        function idx = ExtractIndexFromTag(~, tag)
            % Extracts the numeric part of a tag string
            numStr = regexp(tag, '\d+', 'match');  % find all numbers
            idx = str2double(numStr{1});           % convert first match to number
        end

        function setupMapValues(app, prefix, valuesArrayName)
            for i = 1:22
                compName = prefix + "_" + i;
                app.(compName).Tag = prefix(1)+ "_" + i; % C or T or F
                app.(compName).ValueChangedFcn = @(src,event) MapValueChanged(app,event,valuesArrayName);
                app.(valuesArrayName)(i) = app.(compName).Value;
            end
        end

        function oppositeArrayName = GetOppositeArray(~, valuesArrayName) % not used currently
            % Returns the opposite array name for sliders/values
            switch valuesArrayName
                case "ComfortValues"
                    oppositeArrayName = "ThresholdValues";
                case "ThresholdValues"
                    oppositeArrayName = "ComfortValues";
                otherwise
                    errordlg("No valuesArrayName") % or error/warning if needed
            end
        end

        function stopFlag = CheckMapLevels(app, idx, name, value, valuesArrayName) % error if T is over C % not used rn  
            stopFlag = false;

            switch valuesArrayName
                case "ThresholdValues"
                    if value > app.ComfortValues(idx) 
                        errordlg(name + " is over Comfort!")
                        stopFlag = true;
                    end
                case "ComfortValues"
                    if value < app.ThresholdValues(idx) 
                        errordlg(name + " is under Threshold!")
                        stopFlag = true;
                    end
            end
        end
        
        function CreatePatientFolder(app) 
            tString = GetCurrentTimeStamp(app);
            currentFolder = app.PatientID + tString;

            app.patientFolder = fullfile(app.Directory, currentFolder);
            if ~exist(app.patientFolder, 'dir')
                mkdir(app.patientFolder);
            end
        end
        
        function tString = GetCurrentTimeStamp(~)
            t = datetime('now');
            t.Format = 'HH.mm_dd_MM_yyyy';  % Modern datetime format
            tString = string(t);            % Convert to string
        end

        function GenerateSequenceZoomedPlot(app)
            ZoomFactor = app.ZoomfactorSpinner.Value; 
            runlength = 100; % ms
            
            burst_struct = GenerateBurstStruct(app);

            % 3. Construct the full sequence
            seq_numbursts = ceil(runlength / app.RateEditField_inms.Value);
            seq_current = repmat(burst_struct, 1, seq_numbursts);

            time_ms = (0:length(seq_current)-1) * app.frame_rate_millis;

            % 3. Plot using the time vector
            plot(app.Burst_Example, time_ms, seq_current);
            
            % 4. Adjust the X-Limit to show the zoomed portion in milliseconds
            % This will now correctly show the 3.333ms bursts
            max_time = max(time_ms);
            xlim(app.Burst_Example, [0, max_time / ZoomFactor]);
            
            % Optional: Label your axes so you can verify the units
            xlabel(app.Burst_Example, 'Time (ms)');
            ylabel(app.Burst_Example, 'Amplitude');

        end
        
        function GenerateSeqTabStart(app)
            app.IPG = struct();
            app.IPG.isChanged = 0;
            app.IPG.stepsize = app.frame_rate_millis;

            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            app.RateEditField_inms.Value = round(snapped_value, 4);
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value;
            app.WaitPeriodEditField.Value = app.RateEditField_inms.Value-sum(app.interpulsegaps); % update WaitperiodEditfield

            app.FrameperiodLabel.Text = "Frameperiod [μs]: " + app.frame_rate_micros; % this needs to be updated if not hardcoded anymore

            UpdateMaxPulses(app);
            UpdateInterpulseGaps(app);
        end
        
        function UpdateInterpulseGaps(app)
            
            gapString = strjoin(string(app.interpulsegaps), ', '); % Convert the numeric array to strings, then join them with a comma
            
            app.IPGDisplay.Text = "Interpulsegaps: [" + gapString + "]"; % update the label 
        end
        
        function UpdateMaxPulses(app)

            app.possibleFrames = app.RateEditField_inms.Value/app.frame_rate_millis; % calc max possible frames
            app.roundedpossibleFrames = floor(app.possibleFrames);

            app.fractional_possibleFrames = round(rem(app.possibleFrames, 1), 4); % get the fractional

            app.MaxPulsesLabel.Text = "Maximum Pulses: " + app.possibleFrames; % write the label 

            app.PulsesperburstEditField.Limits(2) = app.roundedpossibleFrames; % limit the max amount of pulses
        end
        
        function [multipleofFrame, middleNumofFrames] = GetbiggestpossiblemultipleofFrameRate(app)
            
            rawIpgValue = app.RateEditField_inms.Value / (app.PulsesperburstEditField.Value-1);

            multipleofFrame = floor(rawIpgValue/app.IPG.stepsize) * app.IPG.stepsize;

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end

            maxIPG = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % interpulsegap Limit
               
            if multipleofFrame > maxIPG, multipleofFrame = maxIPG; end

            middleNumofFrames = round((rawIpgValue/app.IPG.stepsize)/2) * app.IPG.stepsize;

        end
        
        function ResetInterpulseGap(app)
            app.interpulsegaps(:) = app.InterpulsegapEditField.Value;
            UpdateInterpulseGaps(app);
        end
        
        function GenerateFileName(app) % check better naming possiblity
                        
            
            if ~isempty(app.interpulsegaps)
                gapString = strjoin(string(app.interpulsegaps), ', ');
                equalinterpulsegaps = sprintf('%.4f', app.InterpulsegapEditField.Value); % maybe later file to long 
                isUniform = all(app.interpulsegaps(:) == app.interpulsegaps(1));
            end

            % Determine the filename based on pulse count and uniformity
            if app.PulsesperburstEditField.Value == 1
                % Single pulse: No gap (D) needed in filename
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_W', num2str(app.WaitPeriodEditField.Value));
                    
            elseif isUniform
                % Multiple pulses, all gaps are the same
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_G', num2str(equalinterpulsegaps), '_W', num2str(app.WaitPeriodEditField.Value));
                    
            else
                % Not uniform (and implied pulses ~= 1)
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_G', num2str(gapString), '_W', num2str(app.WaitPeriodEditField.Value));
            end

        end
        
        function burst_struct = GenerateBurstStruct(app)

            % 1. Calculate the total frames in ONE burst period
            % This ensures the pulses are spaced out correctly when repeated
            frames_per_burst = round(app.RateEditField_inms.Value / app.frame_rate_millis); % should be an integer alr, round is just tomake sure 
            burst_struct = zeros(1, frames_per_burst); % amount of frames in the struct
            
            burst_numpulses = app.PulsesperburstEditField.Value; % number of pulses
            
            % 2. Calculate pulse positions relative to the start of the burst
            if burst_numpulses > 0 %~isempty(app.interpulsegaps) && 

                current_frame = 1; % First pulse at frame 1
                burst_struct(current_frame) = 1; % Set amplitude

                for i = 1:(burst_numpulses - 1)

                    % Move forward by the gap + 1 pulse width
                    gap_in_frames = round(app.interpulsegaps(i) / app.frame_rate_millis); % also int alr % minus frame to get actual ipg without the pulsetime
                    current_frame = current_frame + gap_in_frames;
                
                    % Safety check: don't exceed the burst period length
                    if current_frame <= frames_per_burst
                        burst_struct(current_frame) = 1;
                    end
                end

            end
        end
        
        function resetMapValues(app, prefix, valuesArrayName)
            % 1. Define the rules
            % 'limit' here refers to the boundary we want to reset for the OTHER component
            rules.ComfortValues   = struct('startValue', 255,   'resetLimits', [0 255], 'isNumeric', true,  "isCheckbox", false);
            rules.ThresholdValues = struct('startValue', 0,   'resetLimits', [0 255], 'isNumeric', true,  "isCheckbox", false);
            rules.FlagElectrodeCheckBoxValues = struct('startValue', 0, 'isNumeric', false, "isCheckbox", true);
            
            if ~isfield(rules, valuesArrayName)
                return; % Safety check
            end
            
            config = rules.(valuesArrayName);

            if config.isCheckbox
                app.FlagunflagallElectrodesCheckBox.Value = false;
            end
            
            for i = 1:length(app.(valuesArrayName))
                compName = prefix + "_" + i;
                target = app.(compName);

                if config.isNumeric
                    target.Limits = [0 255]; % Open limits to avoid "out of range" errors
                end

                target.Value = config.startValue; % Set numeric value
                app.(valuesArrayName)(i) = target.Value;  % Sync the internal array
            end

        end

        function amplitudeList = CalculateDynamicRange(app, amplitudes, electrodes)
            % electrodes = electrodesList{i} - specific electrode indices (e.g., [3,7,12])
            numElectrodes = length(electrodes);
            amplitudeList = zeros(size(amplitudes));
            
            for j = 1:numElectrodes
                electrodeID = electrodes(j);  % Specific electrode value, NOT position
                threshold = app.ThresholdValues(electrodeID);
                comfort = app.ComfortValues(electrodeID);
                
                % Scale input amplitude for this specific electrode
                amplitudeList(j) = threshold + (comfort - threshold) * amplitudes(j);
            end
        end
        
        % function sequencePatternList = GenerateSequencePatternList(app, sequenceList)
        % 
        %     tokenOrderList = GetAllTokenOrders(app);
        %     sequencePatternList = cell(length(tokenOrderList), length(sequenceList), length(sequenceList));
        % 
        %     % % generates all possible Patterns
        %     % for i = 1:length(tokenOrderList)
        %     %     for j = 1:length(sequenceList)
        %     %         for k = 1:length(sequenceList)
        %     %             % for random want to pick a
        %     %             % randperm(tokenOrderList) to create Sequence
        %     %             sequencePattern = GenerateSequencePattern(app, sequenceList(k), sequenceList(j), tokenOrderList{i});
        %     %             sequencePatternNames = sequencePattern.tokenname;
        %     %             sequencePatternList{i, j, k} = sequencePattern;  % Store in 3D cell
        %     % 
        %     %             sequencePattern.electrodes
        %     %             sequencePattern.amplitudes
        %     %             sequencePattern.tokennames
        %     %         end
        %     %     end
        %     % end
        % 
        %     cellfun(@(x) disp(x), sequencePatternList, 'UniformOutput', false);
        %     disp(sequencePatternList);
        % 
        % end

        function stream = GenerateStream(app, Sequence_A, Sequence_B, tokenPattern)

            frameRepetitions = round(app.Stimduration_inmsEditField.Value / app.frame_rate_millis);
        
            stream = struct();
            stream.electrodes = [];
            stream.amplitudes = [];
            stream.tokennames = [];
            
            tokens = regexp(tokenPattern, '[AB]', 'match');  % {'A','B','B','A'}
            positions = regexp(tokenPattern, '[AB]', 'start');  % [1, 3, 5, 7]
            
            tokennames = tokenPattern;  % Start with original pattern "A-B,A"
            
            % Replace A/B with actual filenames (preserving separators)
            tokennames = regexprep(tokennames, 'A', Sequence_A.Filename + "_E:[" + Sequence_A.electrodeValues + "]_A:[" + Sequence_A.amplitudesValues + "]");
            tokennames = regexprep(tokennames, 'B', Sequence_B.Filename + "_E:[" + Sequence_B.electrodeValues + "]_A:[" + Sequence_B.amplitudesValues + "]");
        
            stream.tokennames = tokennames;
            
            for seg = 1:length(tokens)
                % Add pattern
                switch tokens{seg}
                    case 'A'
                        stream.electrodes = [stream.electrodes, Sequence_A.electrodes];
                        stream.amplitudes = [stream.amplitudes, Sequence_A.amplitudes];
                    case 'B'
                        stream.electrodes = [stream.electrodes, Sequence_B.electrodes];
                        stream.amplitudes = [stream.amplitudes, Sequence_B.amplitudes];
                end
                
                % Check gap to NEXT token (same logic)
                if seg < length(tokens)
                    nextPos = positions(seg+1);
                    currEnd = positions(seg) + 1;
                    
                    if nextPos == currEnd + 1  % Adjacent: "AB"
                        interSequence = [];
                    else
                        sepChar = tokenPattern(currEnd);
                        switch sepChar
                            case '-'
                                interSequence = zeros(1, frameRepetitions);
                            case ','
                                interSequence = zeros(1, 2 * frameRepetitions);
                            case ';'
                                interSequence = zeros(1, 3 * frameRepetitions);
                        end
                    end
                    stream.electrodes = [stream.electrodes, interSequence];
                    stream.amplitudes = [stream.amplitudes, interSequence];
                end
        
                    % disp(sequencePattern.electrodes)
                    % disp(sequencePattern.amplitudes)
                    % disp(sequencePattern.tokennames)
            end
        end

        function sequence = genereateSequence(app, File, Filename, Electrodes, Amplitudes) % Token will be used later depending on the picked exp 

                burst = generateBurst(app, File, Electrodes, Amplitudes);

                burstLength = length(File.burst_struct) * app.frame_rate_millis;
                burstRepetitions = round(app.Stimduration_inmsEditField.Value / burstLength);

                sequence = struct();
                
                sequence.electrodes = repmat(burst.electrodes, 1, burstRepetitions);
                sequence.amplitudes = repmat(burst.amplitudes, 1, burstRepetitions);

                sequence.electrodeValues = strjoin(string(burst.electrodesList), ",");
                sequence.amplitudesValues = strjoin(string(burst.amplitudesList), ",");
                

                if ~isempty(Filename)
                    sequence.Filename = Filename;
                end

                if app.isactiveRamping 
                    [rampUpBurst, rampDownBurst, numRampBursts] = GenerateRamps(app, burst);
                            
                    fullBursts = max(0, burstRepetitions - 2 * numRampBursts);
                    fullBurstSeq.amplitudes = repmat(burst.amplitudes, 1, fullBursts);
                    
                    sequence.amplitudes = [rampUpBurst.amplitudes, fullBurstSeq.amplitudes, rampDownBurst.amplitudes];
                end
        end

        function burst = generateBurst(app, File, Electrodes, Amplitudes)

            electrodesList = str2double(split(Electrodes, ',')); % generates List of electrodes in one burst
            amplitudesList = str2double(split(Amplitudes, ','));

            if app.isactiveDynamicRange
                amplitudeList = CalculateDynamicRange(app, amplitudesList, electrodesList);
                amplitudesList = amplitudeList;
            end

            % Get the original burst pattern (preserves 0s and 1s)
            burst_array = File.burst_struct;  % Your 1xN logical/numeric array
            
            % Create full arrays with 0s where no pulse
            burst = struct();
            burst.burst_pattern = burst_array;  % Keep original pattern
            
            % Reconstruct FULL arrays matching burst length
            full_length = length(burst_array);
            burst.electrodes = zeros(1, full_length);  % All 0s initially
            burst.amplitudes = zeros(1, full_length);  % All 0s initially

            burst.electrodesList = electrodesList;
            burst.amplitudesList = amplitudesList;

            % Fill ONLY active positions
            active = find(burst_array == 1);
            
                if length(active) == length(electrodesList)
                    burst.electrodes(active) = electrodesList;
                else
                    warning('Electrodes mismatch: %d active pulses vs %d electrodes', ...
                            length(active), length(electrodesList));
                end
                
                if length(active) == length(amplitudesList)
                    burst.amplitudes(active) = amplitudesList;
                else
                    warning('Amplitudes mismatch: %d active pulses vs %d amplitudes', ...
                            length(active), length(amplitudesList));
                end
            
        end
        
        function GenerateTestSeq(app, File)
            % as Tab selection Callback maybe
            sequence = genereateSequence(app, File, {}, app.TestSeq_Electrodes.Value, app.TestSeq_Amplitudes.Value);
            GeneratePlot(app, sequence, "TestSeq"); % 1 for now later change on row selection, also later create with token pattern

        end
        
        function GenerateAppSettingsStart(app)
            app.isactiveDynamicRange = app.CalculateasDynamicRangeofMapCheckBox.Value;
            app.isactiveTransfer = app.TransfertoNICCheckBox.Value;
            app.isactiveRamping = app.RampingCheckBox.Value;
            app.isactiveLogging = app.GenerateLogCheckBox.Value;
            SetTokenOrder(app, app.TokenPatternDropDown.Value);
        end
        
        function electrodesDisplay = SetElectrodesInput(app, File)
           
            numPulses = nnz(File.burst_struct);
            electrodes = app.Initiate_Electrodes.Value; 
            electrodeList = repmat({electrodes}, 1, numPulses);  % {"E1","E1","E1"...}
            electrodesDisplay = strjoin(electrodeList, ','); % seperate this move out if fjunction
        end
        
        function amplitudesDisplay = SetAmplitudesInput(app, File)
            
            numPulses = nnz(File.burst_struct);
            amplitudes = app.Initiate_Amplitudes.Value; 
            amplitudesStr = num2str(amplitudes);
            amplitudeList = repmat({amplitudesStr}, 1, numPulses);  % {"E1","E1","E1"...}
            amplitudesDisplay = strjoin(amplitudeList, ','); % seperate this move out if fjunction
        end
        
        function SetTestSeqInputs(app, setting, File)

            app.Testseq_ResetAmplitudes.Enable = setting;
            app.TestSeq_ResetElectrodes.Enable = setting;

            app.TestSeq_Amplitudes.Enable = setting;
            app.TestSeq_Electrodes.Enable = setting;

            app.TestSeq_Run.Enable = setting;
            if ~isempty(File)
                numPulses = nnz(File(1).burst_struct);
            else
                numPulses = 0;
            end

            app.TestSeq_PulsesLabel.Text = "Pulses in Burst File: " + num2str(numPulses);

            if strcmp(setting, "off")
                cla(app.TestSeq_Plot);
                app.TestSeq_Filename.Text = "Please select only a single Sequence to Test!";
            end
        end
        
        function GeneratePlot(app, sequencePattern, Tab)

            % Generate the plot (standalone version)
            Plot = app.(Tab + "_Plot");  % Dynamic plot handle
            timeframe = (1:length(sequencePattern.amplitudes)) * app.frame_rate_millis;  % Time axis in ms
            
            % Create the plot
            plot(Plot, timeframe', sequencePattern.amplitudes');
            xlim(Plot, [0 max(timeframe)]);                    % X: 0 to total duration
            ylim(Plot, [0 max(sequencePattern.amplitudes)*1.2]);     % Y: 0 to 120% max amplitude
            
            % Optional: Add labels for clarity
            xlabel(Plot, 'Time (ms)');
            ylabel(Plot, 'Amplitude');
            title(Plot, sprintf('Sequence Amplitudes - Duration: %.1f ms', max(timeframe)));
            grid(Plot, 'off');

        end
        
        function [rampUpBurst, rampDownBurst, numRampBursts] = GenerateRamps(app, burst)
            burstLength = length(burst.amplitudes);  % 17
            burstLength_ms = burstLength * app.frame_rate_millis;  % Scalar!
            
            numRampBursts = round(app.RampingLength_inmsEditField.Value / burstLength_ms);

            % if ~isscalar(numRampBursts), numRampBursts = round(mean(numRampBursts)); end
            if numRampBursts < 1, numRampBursts = 1; end
            
            totalLength = numRampBursts * burstLength;  % Scalar: 1*17=17, 9*17=153, etc.
            rampUpScale = linspace(0.1, 1, totalLength);
            rampDownScale = linspace(1, 0.1, totalLength);
            
            fullOriginalAmp = repmat(burst.amplitudes, 1, numRampBursts);  % [1 x totalLength]
            
            rampUpBurst = burst; rampUpBurst.amplitudes = fullOriginalAmp .* rampUpScale;
            rampDownBurst = burst; rampDownBurst.amplitudes = fullOriginalAmp .* rampDownScale;
        end
        
        function InitiateFileTable(app, File)
            
            numFiles = length(File);
            
            Filename = cell(numFiles, 1);
            Electrodes = cell(numFiles, 1);
            Amplitudes = cell(numFiles, 1);
            
            for i = 1:numFiles
                Filename{i} = app.Unified.Filename{i};
                Electrodes{i} = SetElectrodesInput(app, File(i).data);
                app.Unified.OldElectrodes{i} = Electrodes{i}; % technically unessecary
                Amplitudes{i} = SetAmplitudesInput(app, File(i).data);
                app.Unified.OldAmplitudes{i} = Amplitudes{i};
                sequence = genereateSequence(app, File(i).data, Filename{i}, Electrodes{i}, Amplitudes{i});
                app.Unified.Sequence(i) = sequence; % generate a first Sequence for all the files on the table 
            end

            Remove = repmat({'remove'}, numFiles, 1);

            app.Unified.Electrodes = Electrodes;
            app.Unified.Amplitudes = Amplitudes;
            
            % Create table data ONLY
            tableData = table(Filename, Electrodes, Amplitudes, Remove);
            tableData.Properties.RowNames = Filename;
            
            % UPDATE UI TABLES + HEADERS DIRECTLY
            app.Unified.MasterTable = tableData;

            app.Discrimination_ContinueButton.Enable = "on";
            app.Ranking_ContinueButton.Enable = "on";
            app.Ranking_RunButton.Enable = "on";

            UpdateUnifiedTables(app);  % Helper function below
        end
            
        function UpdateUnifiedTables(app)
            master = app.Unified.MasterTable;
            app.Discrimination_Table.Data = master;
            app.Ranking_Table.Data = master;
            app.Discrimination_Table.ColumnName = master.Properties.VariableNames;
            app.Ranking_Table.ColumnName = master.Properties.VariableNames;
        end

        function ClearUnifiedData(app, fileIdx)
            app.Discrimination_ContinueButton.Enable = "off";
            app.Ranking_RunButton.Enable = "off";
            app.Unified.File(fileIdx) = [];           % Remove file struct
            app.Unified.Filename(fileIdx) = [];       % Remove filename
            app.Unified.Sequence(fileIdx) = [];       % remove sequence   
        end
        
        function CellSelectionCallback(app, event, Tab)
            if isempty(event.Indices)
                    return;
            end
    
            % Handle single OR multiple selections
            row = event.Indices(1,1);
            col = event.Indices(1,2);
            Table = app.(Tab + "_Table");

            if col == 4
                filename = Table.Data{row, 1};
                Table.Data(row, :) = [];

                fileIdx = strcmp(app.Unified.Filename, filename);
                if any(fileIdx)
                    ClearUnifiedData(app, fileIdx)
                end
                Plot  = app.(Tab + "_Plot");
                cla(Plot);
                return
            end

            % filename = Table.Data{row, 1}; % of the row get first col = filename
            % fileIdx = strcmp(app.Unified.Filename, filename); % find matching file to filename 

            fileIdx = row;
            sequence = app.Unified.Sequence(fileIdx);
            GeneratePlot(app, sequence, Tab);
            
        end
        
        function [Amplitudes, OldAmplitudes] = AmplitudesValueChanged(app, File, Electrodes, Amplitudes, OldAmplitudes)
  
            numPulses = nnz(File.burst_struct);

            if isempty(OldAmplitudes)
                OldAmplitudes = app.Initiate_Amplitudes.Value; 
                OldAmplitudes = repmat({OldAmplitudes}, 1, numPulses);
            end

            if isempty(Amplitudes) || (~ischar(Amplitudes) && ~isstring(Amplitudes))
                Amplitudes = strjoin(string(OldAmplitudes), ',');
                return;
            end

            amplitudesList = str2double(strsplit(Amplitudes, ','));

            nonNanList = amplitudesList(~isnan(amplitudesList));
            if isempty(nonNanList) || ~all(isfinite(nonNanList))
                Amplitudes = strjoin(string(OldAmplitudes), ',');
                return;
            end


            if app.isactiveDynamicRange
                amplitudesList = max(0.1, min(1, amplitudesList(isnan(amplitudesList)==0)));  % Remove NaN
            else
                amplitudesList = max(1, min(255, amplitudesList(isnan(amplitudesList)==0)));  % Remove NaN
            end

            % fprintf('electrodesList length: %d, numPulses: %d\n', length(electrodesList), numPulses);
            
            numMultiples = ceil(numPulses / length(amplitudesList));
            
            % Keep NUMERIC - repeat and trim
            amplitudesList = repmat(amplitudesList, 1, numMultiples);
            amplitudesList = amplitudesList(1:numPulses);  % Now 1xnumPulses double array
            
            if ~app.isactiveDynamicRange
                electrodesList = str2double(split(Electrodes , ','));
                electrodesList = repmat(electrodesList, 1, numMultiples);
                electrodesList = electrodesList(1:numPulses);
                
                % Vectorized lookup - get comfort/threshold for each electrode
                thresholdValues = arrayfun(@(e) app.ThresholdValues(e), electrodesList);
                comfortValues = arrayfun(@(e) app.ComfortValues(e), electrodesList);
                
                % Now clamp each amplitude with its electrode's limits
                validIdx = ~isnan(amplitudesList);
                amplitudesList(validIdx) = max(thresholdValues(validIdx), ...
                                              min(comfortValues(validIdx), amplitudesList(validIdx)));
            end
            % Convert to string for UI
            Amplitudes = strjoin(string(amplitudesList), ',');

            OldAmplitudes = amplitudesList;
        end
        
        function [Electrodes, OldElectrodes] = ElectrodesValueChanged(app, File, Electrodes, OldElectrodes)

            numPulses = nnz(File.burst_struct);

            if isempty(OldElectrodes)
                OldElectrodes = app.Initiate_Electrodes.Value; 
                OldElectrodes = repmat({OldElectrodes}, 1, numPulses);
            end

            electrodesList = str2double(strsplit(Electrodes, ','));

            nonNanList = electrodesList(~isnan(electrodesList));
            if isempty(nonNanList) || ~all(isfinite(nonNanList))
                Electrodes = strjoin(string(OldElectrodes), ',');
                return;
            end

            electrodesList = max(1, min(22, electrodesList(isnan(electrodesList)==0)));  % Remove NaN
            
            % fprintf('electrodesList length: %d, numPulses: %d\n', length(electrodesList), numPulses);
            
            numMultiples = ceil(numPulses / length(electrodesList));  % Use ceil to avoid truncation
            
            % Generate exactly numPulses elements
            electrodeList = repmat(num2cell(electrodesList), 1, numMultiples);
            electrodeList = electrodeList(1:numPulses);  % Trim to exact length
            
            Electrodes = strjoin(string(electrodeList), ',');

            OldElectrodes = electrodeList;

        end
        
        function CellEditCallback(app, event, Tab)

            if isempty(event.Indices)
                    return;
            end
    
            % Handle single OR multiple selections
            row = event.Indices(1,1);
            col = event.Indices(1,2);
            Table = app.(Tab + "_Table");

            % filename = Table.Data{row, 1};
            % fileIdx = strcmp(app.Unified.Filename, filename); % doesnt
            % work for multiple files with the same name 

            fileIdx = row;

            if col == 2
                Electrodes_cell = Table.Data{row, col};

                Electrodes = Electrodes_cell{1};      % cell → string

                [Electrodes, OldElectrodes] = ElectrodesValueChanged(app, app.Unified.File(fileIdx).data, ...
                    Electrodes, app.Unified.OldElectrodes{fileIdx});

                app.Unified.OldElectrodes{fileIdx} = OldElectrodes;
                app.Unified.Electrodes{fileIdx} = Electrodes;

                Table.Data{row, col} = {Electrodes};
            end

            if col == 3  
                Amplitudes_cell = Table.Data{row, col};
                Electrodes_cell = Table.Data{row, 2};

                Amplitudes = Amplitudes_cell{1};
                Electrodes = Electrodes_cell{1};      % cell → string

                [Amplitudes, OldAmplitudes] = AmplitudesValueChanged(app, app.Unified.File(fileIdx).data, Electrodes, ...
                    Amplitudes, app.Unified.OldAmplitudes{fileIdx}); % app.UNified is the properity in which its saved. File is the struct. data is how to access the saved data in a file and burst_struct is how the struct is named when created.
                
                app.Unified.OldAmplitudes{fileIdx} = OldAmplitudes;
                app.Unified.Amplitudes{fileIdx} = Amplitudes;

                Table.Data{row, col} = {Amplitudes};
            end

            sequence = genereateSequence(app, app.Unified.File(fileIdx).data, Table.Data{row, 1}{1}, Table.Data{row, 2}{1}, Table.Data{row, 3}{1}); % {1} gets the input of the cell
            app.Unified.Sequence(fileIdx) = sequence; % generate a first Sequence for all the files on the table 
            GeneratePlot(app, sequence, Tab);
        end
        
        function AddFilesToTable(app)
            persistent specifiedfolder
            
            mainfolder = app.Directory;
            subfolder = app.brustsfolder;

            if isempty(specifiedfolder)
                specifiedfolder = fullfile(mainfolder, subfolder);
            end
            
            [fileNames, pathName] = uigetfile( ...
                {'*.mat','MAT files (*.mat)'}, ...
                'Select Multiple Files', ...
                'MultiSelect', 'on', specifiedfolder);
            
            % Handle cancel - works for both single and multi
            if isequal(fileNames, 0)
                return;
            end
           
            % Always treat as cell for consistency
            if ~iscell(fileNames)
                fileNames = {fileNames};
            end
            
            numFiles = length(fileNames);
            burstFile = struct('data', {}, 'filename', {});  
            
            for i = 1:numFiles
                fullPath = fullfile(pathName, fileNames{i});
                fprintf('Loading: %s\n', fullPath);
                burstFile(i).data = load(fullPath);
                % burstFile(i).filename = fileNames{i};
                [~, burstFile(i).filename, ~] = fileparts(fileNames{i}); % remove file extensions
            end
            
            % Append and update table
            app.appendIfNotEmpty('File', burstFile);
            app.appendIfNotEmpty('Filename', {burstFile.filename});
                        
            if ~isfield(app.Unified, "MasterTable") || isempty(app.Unified.MasterTable)
                UpdateTableNameLabels(app, "No name specified");
                InitiateFileTable(app, app.Unified.File);
            else
                newRows = cell(numFiles, 4);  % Fixed 4 columns
            
                for i = 1:numFiles
                    Electrodes = SetElectrodesInput(app, burstFile(i).data);
                    Amplitudes = SetAmplitudesInput(app, burstFile(i).data);
                    newRows(i,1) = {burstFile(i).filename};
                    newRows(i,2) = {Electrodes};
                    newRows(i,3) = {Amplitudes};
                    newRows(i,4) = {"remove"};
                    oldElectrodes = Electrodes;
                    sequence = genereateSequence(app, burstFile(i).data, burstFile(i).filename, Electrodes, Amplitudes);
                    app.appendIfNotEmpty('OldElectrodes', oldElectrodes);
                    app.appendIfNotEmpty('Sequence', sequence);
                end 

                app.Unified.MasterTable = [app.Unified.MasterTable; newRows];
                UpdateUnifiedTables(app); 
            end
            
            specifiedfolder = pathName;  % Remember for next time
        end

        function ClearTable(app) % tables are supposed to be one anyways
            app.Unified = struct(); 

            app.Discrimination_Table.Data = [];
            app.Ranking_Table.Data = [];

            cla(app.Discrimination_Plot);
            cla(app.Ranking_Plot);

            app.Discrimination_ContinueButton.Enable = "off";
            app.Ranking_ContinueButton.Enable = "off";
            app.Ranking_RunButton.Enable = "off";

            UpdateTableNameLabels(app, "No name specified");
        end
        
        function appendIfNotEmpty(app, fieldName, value)
            if ~isfield(app.Unified, fieldName) || isempty(app.Unified.(fieldName))
                app.Unified.(fieldName) = value;
            else
                app.Unified.(fieldName) = [app.Unified.(fieldName), value];
            end
        end

        function UpdateTableNameLabels(app, Name)
            app.Discrimination_TablenameLabel.Text = "Table: " + Name;
            app.Ranking_TablenameLabel.Text = "Table: " + Name;
        end
        
        function SaveTableData(app, Table, Filename, File, Electrodes, Amplitudes, OldElectrodes, OldAmplitudes)
            mainfolder = app.Directory;
            subfolder = app.tablesfolder;

            specifiedfolder = fullfile(mainfolder, subfolder);

            if ~exist(specifiedfolder, "dir")
                mkdir(specifiedfolder)
            end

            SaveData.Table = Table;
            SaveData.Filenames = Filename;
            SaveData.Files = File;
            SaveData.Electrodes = Electrodes;
            SaveData.Amplitudes = Amplitudes;  
            SaveData.OldElectrodes = OldElectrodes;
            SaveData.OldAmplitudes = OldAmplitudes;

            tString = GetCurrentTimeStamp(app);
            SaveData.TableName = app.PatientIDEditField.Value + "_Rows" + num2str(length(SaveData.Filenames)) + "_" + tString;  % User-specified table name

            % Save entire Unified struct with table name
            defaultLocation = fullfile(specifiedfolder, ...
                strcat(SaveData.TableName, '.mat'));
            
            % defaultLocation = fullfile(specifiedfolder, strcat(app.FilenameEditField.Value, '.mat'));
            
            % 1. Open the dialog
            [file, folder] = uiputfile(defaultLocation, 'Save Table Structure');
            
            % 2. Check if the user actually selected a file (did not click cancel)
            if ischar(file) || isstring(file)
                % 3. Use fullfile for cross-platform compatibility (Windows/Mac/Linux)
                fullSavePath = fullfile(folder, file);
                
                % 4. Save the variable
                save(fullSavePath, 'SaveData');
                
                % Optional: Provide feedback to the user
                fprintf('Table file saved successfully: %s\n', fullSavePath);

                UpdateTableNameLabels(app, SaveData.TableName);
            else
                % User clicked cancel
                disp('Save cancelled by user.');
            end
        end
        
        function [tokens, separators] = ExtractTokensAndSeparators(app, tokenPattern)

            tokens = regexp(tokenPattern, '[AB]', 'match');
            separators = regexp(tokenPattern, '[-,;]', 'match');
            % Pad separators to match token gaps if needed
            if length(separators) < length(tokens)-1
                separators{end+1} = '';
            end
        end

        function perms = GenerateProportionalPermutations(app, numA, numB)
            numTokens = numA + numB;
            
            if numTokens == 0
                perms = {};
                return;
            end
            
            % Generate all possible B positions among total tokens
            bPositions = nchoosek(1:numTokens, numB);
            
            % Create all permutations
            result = {};
            for i = 1:size(bPositions, 1)
                pattern = repmat('A', 1, numTokens);
                pos = bPositions(i,:);
                for j = 1:length(pos)
                    pattern(pos(j)) = 'B';
                end
                result{end+1} = pattern;
            end
            
            % Remove duplicates (if any)
            perms = unique(result);
        end

        function allTokenOrders = ReinsertSeparators(app, allTokenPerms, separators)
            allTokenOrders = {};
            
            for i = 1:length(allTokenPerms)
                tokenStr = allTokenPerms{i};  % e.g. 'AABA'
                result = tokenStr(1);         % Start with first token
                
                % Insert separators between tokens
                for j = 1:length(separators)
                    if j < length(tokenStr)
                        result = [result, separators{j}, tokenStr(j+1)];
                    end
                end
                
                allTokenOrders{i} = result;
            end
        end

        function allTokenOrders = SetAllPossibleTokenOrder(app, tokenPattern)
            % Step 1: Original proportions permutations
            [tokens, separators] = ExtractTokensAndSeparators(app, tokenPattern);
            numA = sum(strcmp(tokens, 'A'));
            numB = sum(strcmp(tokens, 'B'));
            
            if (numA + numB) > 2
            % Generate original permutations
            origPerms = GenerateProportionalPermutations(app, numA, numB);

            % Reinsert original separators into each
            allTokenOrders = ReinsertSeparators(app, origPerms, separators);

            else
                allTokenOrders = tokenPattern;
            end
        end
        
        function SetTokenOrder(app, Pattern)
            switch Pattern
                case "Odd Token Out"
                    tokenPattern = "A-B-A";
                case "2 Alternate Out"
                    tokenPattern = "A-B";
                case "4I2AFC"
                    tokenPattern = "A-A, A-B";
            end

            allTokenOrders = SetAllPossibleTokenOrder(app, tokenPattern);
            app.TokenOrderDropDown.Items = allTokenOrders;
            if length(app.TokenOrderDropDown.Items) > 1
                app.TokenOrderDropDown.Value = app.TokenOrderDropDown.Items{2};
            end
        end
        
        function tokenOrderList = GetAllTokenOrders(app)
            if app.AllTokenOrdersCheckBox.Value
                tokenOrders = app.TokenOrderDropDown.Items;
                tokenOrderList = tokenOrders;
            else
                tokenOrder = app.TokenOrderDropDown.Value;
                tokenOrderList = {tokenOrder};
            end
        end
        
        
    %   function RankingExp(app)
    %         if app.RunPitchRanking == 1 && app.ApplyOnPitchExpButton.Text == "Applied"
    % 
    % runnumber = num2str(app.RunOfTheDay);
    % version = 1;
    % 
    % app.RunPitchRanking = false;
    % app.RunPitchdiscrimination = false;
    % app.CreateallsequencesfortheexperimentLabel.Visible = 'on';   
    % app.SelectfullsequencesbelowtoEXCLUDEfromtheexperimentLabel.Visible = "off";
    % app.SequencesListBox_3.Visible = 'off'; 
    % app.PitchExpTable.Enable = "off"; 
    % app.PitchExpTable.Visible = "on"; 
    % app.ApplyOnPitchExpButton.Enable = "off";
    % app.ApplyOnPitchExpButton.Visible = "on";
    % app.EditPitchExpButton.Visible = "on";
    % app.EditPitchExpButton.Enable = "off";
    % 
    % file = app.PitchExpTable.Data(:, 1);
    % 
    % numRows = size(app.PitchExpTable.Data, 1);
    % experimentSequences = cell(numRows, 1);
    % matFiles = cell(numRows, 1);
    % isCancelled = false;
    % for i = 1:numRows
    %     experimentSequences{i} = app.sequencePart{i};
    %     matFiles{i} = load(string(app.path)+string(file{i})+'.mat');
    % end
    % 
    % numSeq = length(experimentSequences);
    % rankedSeq = {};
    % 
    % sheetNames = {};
    % allTables = {};
    % 
    % intersequencetime = app.intersequencemsEditField.Value; %time between sequences in a trial in ms
    % intersequencetime_f = round(intersequencetime*1000/app.frame_period);
    % 
    % null_intersequence_electrodes = zeros(1,intersequencetime_f);
    % null_intersequence_mode = zeros(1,intersequencetime_f);
    % null_intersequence_current = zeros(1,intersequencetime_f);
    % null_intersequence_phase = zeros(1,intersequencetime_f);
    % null_intersequence_ipg = zeros(1,intersequencetime_f);
    % 
    % %% Check if runtime is too long      
    % 
    % all_seq_electrodes = 0;
    % 
    % for l = 1:numSeq
    %     this_seq_electrodes = [experimentSequences{l}.electrodes, null_intersequence_electrodes, experimentSequences{l}.electrodes];
    %     all_seq_electrodes = all_seq_electrodes + length(this_seq_electrodes);
    % end
    % 
    % mid_seq_electrodes = all_seq_electrodes/numSeq;
    % 
    % preStimulationTime = 3e5; %in micro s % 300000
    % stimulationTime_inmicros = preStimulationTime + mid_seq_electrodes*app.frame_period; 
    % stimulationTime_inmillis = stimulationTime_inmicros/1e3; % 1000
    % stimulationTime = stimulationTime_inmillis/1e3; % in s
    % 
    % buttonClick = 0.5; % estimated per trial
    % 
    % timePerDecision = stimulationTime + buttonClick; % in s
    % 
    % decisionRepetitions = app.PitchRankingDecisionRepetitionEditField.Value;
    % trialsPerRun = numRows; % decisions needed per run 
    % 
    % trialPartTillDecisionList = zeros(1, trialsPerRun);
    % trialPartTillDecisionList(1) = 1;
    % 
    % for i = 2:trialsPerRun
    % 
    %     if mod(i, 2) == 0
    %         trialPartTillDecisionList(i) = trialPartTillDecisionList(i-1) + 1;
    %     else 
    %         trialPartTillDecisionList(i) = trialPartTillDecisionList(i-1); 
    %     end
    % end
    % 
    % maxDecisionsPerRun = sum(trialPartTillDecisionList);
    % 
    % maxTrialsperRun = maxDecisionsPerRun*decisionRepetitions;
    % Runs = app.RunsEditField.Value;
    % 
    % totalTrials = maxTrialsperRun * Runs;
    % 
    % maxRunTime = timePerDecision * totalTrials;
    % 
    % if maxRunTime>60
    %     runtimestring = strcat(num2str(maxRunTime/60),{' '}, 'minutes');
    % else
    %     runtimestring = strcat(num2str(maxRunTime),{' '}, 'seconds');
    % end
    % 
    % choice = menu(strcat('Note: Maximum runtime =',{' '},runtimestring),'Continue','Cancel');
    % 
    % if choice == 2 || choice == 0
    %     app.EditPitchExpButton.Visible = "on";
    %     app.EditPitchExpButton.Enable = "on";
    %     return
    % end
    % 
    % max_version = 0; 
    % base_name_pattern = strcat(app.PatientIDEditField.Value, '_V.'); 
    % search_pattern = strcat(base_name_pattern, '*');
    % full_search_path = fullfile(app.LogDirectory, search_pattern);
    % 
    % % 2. Search for all matching folders using dir
    % all_items = dir(full_search_path);
    % folder_info = all_items([all_items.isdir]); %%% Filter to include only folders
    % 
    % % 3. Loop through existing folders to find the maximum version number
    % for k = 1:length(folder_info)
    %     folder_name = folder_info(k).name;
    % 
    %     % Check if the folder name contains the expected base pattern
    %     if startsWith(folder_name, base_name_pattern)
    %         % Extract the numeric part (e.g., if name is 'P001_V.5', this extracts '5')
    %         numeric_part = folder_name(length(base_name_pattern) + 1 : end);
    % 
    %         current_version = str2double(numeric_part);
    % 
    %         % Update max_version if a higher valid number is found
    %         if ~isnan(current_version) && current_version > max_version
    %             max_version = current_version;
    %         end
    %     end
    % end
    % 
    % % 4. Determine the unique version number to use
    % version = max_version + 1; 
    % 
    % % 5. Construct the new, unique folder path
    % current_log_Directory = strcat(app.LogDirectory, '\', app.PatientIDEditField.Value, '_V.', num2str(version));
    % 
    % V = version;
    % 
    % if ~isfolder(current_log_Directory)
    %     mkdir(current_log_Directory); 
    %     disp(['Created folder structure: ', current_log_Directory]);
    % end
    % 
    % current_log_path = strcat(current_log_Directory, '\', ...
    % app.PatientIDEditField.Value, '_RUN_IN_PROGRESS.csv');
    % 
    % finalRankVarNames = cell(1, app.RunsEditField.Value);
    % for k = 1:app.RunsEditField.Value
    %     finalRankVarNames{k} = ['Ranked Sequence Run ', num2str(k)];
    % end
    % 
    % % 2. Create the corresponding Variable Types (all 'cell' for holding sequence strings)
    % finalRankVarTypes = repmat({'cell'}, 1, app.RunsEditField.Value);    
    % 
    % % 3. Define the table
    % allRankedSeq = table('Size', [length(rankedSeq), app.RunsEditField.Value], ...
    %     'VariableTypes', finalRankVarTypes, ...
    %     'VariableNames', finalRankVarNames);
    % 
    % for i = 1:app.RunsEditField.Value %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% for i numRuns
    % 
    % results = table('Size', [0, 13], ...
    % 'VariableTypes', { ...
    % 'cell',   % TrialNumber - Correct way to specify a cell column
    % 'cell',   % DateAndTime
    % 'cell',   % SequencePresented
    % 'cell',   % ButtonPressed
    % 'cell',   % Amplitudes 1
    % 'cell',   % Electrodes 1
    % 'cell',   % Amplitudes 2
    % 'cell',   % Electrodes 2
    % 'cell',   % Mode
    % 'cell',   % Phase
    % 'cell',   % InterphaseGap
    % 'cell',   % InterSequenceInterval
    % 'cell'}, ... % notes
    % 'VariableNames', {'TrialNumber', 'DateAndTime', 'SequencePresented', ...
    % 'ButtonPressed', 'Amplitudes 1', 'Electrodes 1', 'Amplitudes 2', 'Electrodes 2',...
    % 'Mode', 'Phase', 'InterphaseGap', 'InterSequenceInterval', 'Decision'});
    % 
    % randomOrderIndices = randperm(numSeq);
    % randomSeq = experimentSequences(randomOrderIndices); 
    % 
    % runcounter = 0;
    % rankedSeq = {};
    % 
    % rowCount = 0; %%% CHANGED: Initialize rowCount to 0 at the start of the run (instead of 1) so it increments correctly inside loops
    % RankedSeq_log_path = strcat(current_log_Directory, '\', ...
    % app.PatientIDEditField.Value, '_RankedSeq_(High_to_Low)_RUN_', '_IN_PROGRESS.csv'); % , num2str(i)
    % 
    % testRepetition = app.PitchRankingDecisionRepetitionEditField.Value;
    % isUnanimousDecision = app.Decideafter2unanimousDecisionsCheckBox.Value; 
    % 
    % isCancelled = false;
    % 
    %     for m = 2:numSeq  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% for loop 2 numSeq
    % 
    %     trialcounter = 1;
    %     runcounter = runcounter + 1;
    % 
    %     testSeq = randomSeq{m};
    % 
    %         if isempty(rankedSeq)
    %         numRankedSeq = 0; % PROBABLY ISSUE WITH WHILE LOOP     
    %         else
    %         numRankedSeq = length(rankedSeq);
    %         end
    % 
    %         upperCap = numRankedSeq;
    %         lowerCap = 1;
    % 
    % 
    %         while (lowerCap <= upperCap || isempty(rankedSeq) ) && isCancelled == 0      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 2 while loop 
    % 
    %             rowCount = rowCount + 1; 
    % 
    %             isdecided = false;
    %             choiceList = {}; 
    % 
    %             if isempty(rankedSeq)
    %             n = m - 1; 
    %             compareSeq = randomSeq{n}; 
    %             else
    %             middleNum = lowerCap + floor((upperCap - lowerCap) / 2);
    %             compareSeq = rankedSeq{middleNum};
    %             end
    % 
    %             while isdecided == 0 && isCancelled == 0
    % 
    %             if ~isempty(choiceList) && mod(length(choiceList), 2) ~= 0 && ~strcmp(choice, 'Repeat')
    %                 [testSeq, compareSeq] = reverseSequences(app, testSeq, compareSeq);                        
    %             end
    % 
    %             seq_electrodes = [compareSeq.electrodes,...
    %             ones(size(null_intersequence_electrodes)),...
    %             testSeq.electrodes];
    % 
    %             seq_current = [compareSeq.current_levels,...
    %             null_intersequence_current,...
    %             testSeq.current_levels];
    % 
    %             seq_mode = app.modes;
    % 
    %             seq_phase = app.StimphasesEditField_A_2.Value;
    % 
    %             seq_ipg = app.InterphasegapsEditField_A_2.Value;
    % 
    %             pitchSequence = struct('electrodes',seq_electrodes,'modes',seq_mode,'current_levels',seq_current,'phase_widths',seq_phase,'phase_gaps',seq_ipg, ...
    %             'periods',app.frame_period);
    % 
    %                 if app.GoLiveCheckBox.Value == 1%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% if go live
    % 
    %                 properties = java.util.HashMap;
    %                 properties.put('platform', 'SP16');
    %                 properties.put('implant', 'CIC4');
    %                 properties.put('mode', app.ModeDropDown.Value);
    %                 properties.put('min_pulse_width_us', '25.0');
    % 
    %                     if app.GoLiveCheckBox.Value == 1
    %                         properties.put('go_live', 'on');
    %                     else
    %                         properties.put('go_live', 'off');
    %                     end
    % 
    %                 properties.put('auto_pufs', 'on');
    %                 properties.put('verbose', 'on');
    %                 properties.put('flagged_electrodes', app.flagged_electrodes);
    %                 properties.put('latency_ms', '100');
    %                 properties.put('c_levels_pulse_width_us', num2str(app.c_levels_pulse_width.Value));
    %                 properties.put('c_levels', app.c_lvls);
    %                 client = com.cochlear.nic.nic4.Streamer(properties);
    % 
    %                 client.start();
    %                 client.sendData(cochlear.nic.nic4.struct2map(pitchSequence));
    %                 client.waitUntilFinished();
    %                 client.stop();
    %                 client.close();
    % 
    %                 end 
    % 
    %                 parttrialcount = string(runcounter) + '_' + string(trialcounter);         
    % 
    %                 [choice, isCancelled, ~, f] = showComparisonGUI(app, parttrialcount, compareSeq, testSeq, rankedSeq, i);
    % 
    %                 sequencenames_Ranking{rowCount} = string(compareSeq.filename)+' '+string(compareSeq.EandA)+'_'+string(testSeq.filename)+' '+string(testSeq.EandA);
    %                 trialnumber_Ranking{rowCount} = parttrialcount;
    %                 DateandTime_Ranking{rowCount} = datestr(datetime);
    %                 sequence_playorder_Ranking{rowCount} = sequencenames_Ranking{rowCount};
    % 
    %                 Amplitudechosen1_Ranking{rowCount} =  num2str(compareSeq.usedAmplitudes);
    %                 Amplitudechosen2_Ranking{rowCount} =  num2str(testSeq.usedAmplitudes);
    % 
    %                 ElectrodeChosen1_Ranking{rowCount} = num2str(compareSeq.usedElectrodes);
    %                 ElectrodeChosen2_Ranking{rowCount} = num2str(testSeq.usedElectrodes);
    % 
    %                 mode_chosen_Ranking{rowCount} = app.modes;
    %                 seq_phase_chosen_Ranking{rowCount} = app.StimphasesEditField_A_2.Value;
    %                 seq_ipg_chosen_Ranking{rowCount} = app.InterphasegapsEditField_A_2.Value;
    %                 Timebetweensequencesms_Ranking{rowCount} = app.intersequencemsEditField.Value;
    %                 puttonpressed_Ranking{rowCount} = choice;
    %                 Decision_Ranking{rowCount} = '';
    % 
    %                 if app.GoLiveCheckBox.Value == 1 
    %                     properties = java.util.HashMap;
    %                     properties.put('platform', 'SP16');
    %                     properties.put('implant', 'CIC4');
    %                     properties.put('mode', app.ModeDropDown.Value);
    %                     properties.put('min_pulse_width_us', '25.0');
    %                     properties.put('go_live', 'off');
    % 
    %                     base_seq_name = sprintf('%s_E%s_A%s-%s_E%s_A%s', ...
    %                         string(compareSeq.filename), string(compareSeq.logName_Ele), string(compareSeq.logName_CLs), ...
    %                         string(testSeq.filename), string(testSeq.logName_Ele), string(testSeq.logName_CLs) ...
    %                     );
    % 
    %                     logfilename = string(current_log_Directory) + '\' + ...
    %                     string(app.PatientIDEditField.Value) + '_' + base_seq_name + '_Run_' + i + '_Trial_' + parttrialcount + '_Version_' + V + '.txt';
    % 
    %                     properties.put('log_stream_format', 'basic');
    %                     properties.put('log_stream_filename', char(logfilename));
    % 
    %                     properties.put('auto_pufs', 'on');
    %                     properties.put('verbose', 'on');
    %                     properties.put('flagged_electrodes', app.flagged_electrodes);
    %                     properties.put('latency_ms', '100');
    %                     properties.put('c_levels_pulse_width_us', num2str(app.c_levels_pulse_width.Value));
    %                     properties.put('c_levels', app.c_lvls);
    %                     client = com.cochlear.nic.nic4.Streamer(properties);
    % 
    %                     client.start();
    %                     client.sendData(cochlear.nic.nic4.struct2map(pitchSequence));
    %                     client.waitUntilFinished();
    %                     client.stop();
    %                     client.close();
    %                 end
    % 
    %                 if strcmp(choice, 'Cancelled') || isCancelled == true
    %                     app.EditPitchExpButton.Enable = "on";
    %                     disp("return activated")
    %                     return;      
    %                 end
    % 
    %                 if ~strcmp(choice, 'Repeat')
    %                     choiceList{end+1} = choice;             
    %                 end
    % 
    %                 if testRepetition ~= 1
    %                 if length(choiceList) >= 2 
    %                      if isequal(choiceList{1}, choiceList{2}) || isUnanimousDecision == 0
    %                          if length(choiceList) == testRepetition
    %                             unevenChoices = choiceList(1:2:end);
    %                             evenChoices = choiceList(2:2:end);
    % 
    %                             testIsHigher1 = strcmp('Higher', unevenChoices);
    %                             testIsHigher2 = strcmp('Lower', evenChoices);
    % 
    %                             numtestIsHigher = sum(testIsHigher1) + sum(testIsHigher2);
    %                             numtestIsLower = sum(~testIsHigher1) + sum(~testIsHigher2);
    % 
    %                             if numtestIsHigher > numtestIsLower
    %                                 choice = 'Higher';
    %                                 Decision_Ranking{rowCount} = 'Test Sequences was more often Higher';
    %                                 isdecided = true;
    %                             elseif numtestIsHigher < numtestIsLower
    %                                 choice = 'Lower';
    %                                 Decision_Ranking{rowCount} = 'Test Sequences was more often Lower';
    %                                 isdecided = true;
    %                             else
    %                                 items = {'Higher', 'Lower'}; 
    %                                 shuffled = items(randperm(2));
    %                                 choice = shuffled{1};
    %                                 Decision_Ranking{rowCount} = ['Random Decision after', num2str(testRepetition)];
    %                                 isdecided = true;
    %                             end
    %                          end
    %                      else
    %                         choice = choiceList{1};
    %                         Decision_Ranking{rowCount} = 'unanimous Decision after 2';
    %                         isdecided = true;
    %                      end
    %                 end
    %                 else
    %                     choice = choiceList{1};
    %                     isdecided = true;
    %                 end
    % 
    %                 if isdecided == 1
    %                     if isempty(rankedSeq)
    %                         if strcmp(choice, 'Higher')
    %                             rankedSeq = {testSeq, compareSeq}; 
    %                         else
    %                             if strcmp(choice, 'Lower')
    %                                 rankedSeq = {compareSeq, testSeq};
    %                             end
    %                         end
    %                     else
    %                         if strcmp(choice, 'Higher')
    %                             upperCap = middleNum - 1;
    %                         else
    %                             if strcmp(choice, 'Lower')
    %                                 lowerCap = middleNum + 1;
    %                             end
    %                         end
    %                     end
    %                 end
    % 
    %            dataRow = {
    %                 {trialnumber_Ranking{rowCount}}, ...
    %                 {DateandTime_Ranking{rowCount}}, ...
    %                 {sequence_playorder_Ranking{rowCount}}, ...
    %                 {puttonpressed_Ranking{rowCount}}, ...
    %                 {Amplitudechosen1_Ranking{rowCount}}, ...
    %                 {ElectrodeChosen1_Ranking{rowCount}}, ...
    %                 {Amplitudechosen2_Ranking{rowCount}}, ... 
    %                 {ElectrodeChosen2_Ranking{rowCount}}, ...
    %                 {mode_chosen_Ranking{rowCount}}, ...
    %                 {seq_phase_chosen_Ranking{rowCount}}, ...
    %                 {seq_ipg_chosen_Ranking{rowCount}}, ...
    %                 {Timebetweensequencesms_Ranking{rowCount}}, ...
    %                 {Decision_Ranking{rowCount}},...
    %                 };
    % 
    %             newRow = cell2table(dataRow, 'VariableNames', results.Properties.VariableNames);
    %             results = [results; newRow];
    % 
    %             writetable(results, current_log_path);
    % 
    %             if ~strcmp(choice, 'Repeat')
    %                 trialcounter = trialcounter + 1;
    %             end
    % 
    %             if ~isempty(choiceList) && mod(length(choiceList), 2) == 0 && ~strcmp(choice, 'Repeat')
    %                 [testSeq, compareSeq] = reverseSequences(app, testSeq, compareSeq);                        
    %             end
    % 
    %             end                      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% decided loop 
    % 
    % 
    %         end   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% while loop 
    % 
    %         if numRankedSeq > 0
    %             insertIndex = lowerCap; 
    %             new_length = length(rankedSeq) + 1;
    %             new_rankedSeq = cell(1, new_length);
    %                 if insertIndex > 1
    %                     new_rankedSeq(1:insertIndex - 1) = rankedSeq(1:insertIndex - 1);
    %                 end
    %             new_rankedSeq{insertIndex} = testSeq;
    %                 if insertIndex <= length(rankedSeq)
    %                     new_rankedSeq(insertIndex + 1 : end) = rankedSeq(insertIndex : end);
    %                 end
    %             rankedSeq = new_rankedSeq;
    %         end 
    % 
    %         dataColumn = cell(length(rankedSeq), 1);
    %         for row = 1:length(rankedSeq)
    %             dataColumn{row} = [rankedSeq{row}.filename, ' ', rankedSeq{row}.EandA];
    %         end
    % 
    %         allRankedSeq(1:length(rankedSeq), i) = dataColumn;
    %         writetable(allRankedSeq, RankedSeq_log_path);
    % 
    % 
    %     end               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% for loop 2 numSeq
    % 
    % final_data_to_save = results;
    % d = datestr(datetime);
    % 
    % short_sheet_name = ['Run_', num2str(i)];
    % allTables{end+1} = final_data_to_save; %%% Append the table
    % sheetNames{end+1} = short_sheet_name; %%% Append the valid sheet name
    % 
    % % Delete the 'IN_PROGRESS' file if the run completed successfully
    % if exist(current_log_path, 'file') == 2
    %     delete(current_log_path);
    % end
    % 
    % if exist('f', 'var') && isvalid(f) %%% Added check for 'f' existence
    %     close(f);
    % end    
    % 
    % end  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% for i numRuns
    % 
    % %excelFilename = 'MultiSheet_Export.xlsx';
    % overall_logfilename = strcat(app.LogDirectory, '\', app.PatientIDEditField.Value,'_PitchRank_Overall_', d(1:11), '_Version_', num2str(V), '.xlsx');
    % num_tables_to_save = length(allTables); 
    % 
    % for k = 1:num_tables_to_save
    % currentTable = allTables{k};
    % currentSheet = sheetNames{k};
    % writetable(currentTable, overall_logfilename, 'Sheet', currentSheet);
    % end
    % 
    % finalSeq_logfilename = strcat(current_log_Directory, '\', ...
    % app.PatientIDEditField.Value,'_PitchRank_', d(1:11), 'all_Runs_Ranked_Seq(High_to_Low)_Version_', num2str(V), '.csv'); %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % 
    % 
    % % Save the entire populated table
    % writetable(allRankedSeq, finalSeq_logfilename); % CHANGED: Writing the whole table
    % 
    % % The final CSV file will now have length(rankedSeq) rows and numRuns columns.
    % RankedSheet = 'Ranked Sequences';
    % writetable(allRankedSeq, overall_logfilename, 'Sheet', RankedSheet);
    % if exist(RankedSeq_log_path, 'file') == 2
    %   delete(RankedSeq_log_path);
    % end
    % 
    % choice = menu('Runs Over','Continue');
    % if choice == 1
    %     app.EditPitchExpButton.Visible = "on";
    %     app.EditPitchExpButton.Enable = "on";
    %     return
    % end
    %     end
        
        function SetUpListBox(app, Experiment)

            app.Unified.stream = {};
            app.Unified.streamName = {};
          
            listBoxName = [Experiment '_ListBox'];
            excludeCheckboxName = [Experiment '_ExcludeCatchTrialsCheckBox'];
            excludeCheckbox = app.(excludeCheckboxName).Value;

            sequenceList = app.Unified.Sequence;
            tokenOrderList = GetAllTokenOrders(app);
            sequencePatternList = cell(length(tokenOrderList), length(sequenceList), length(sequenceList));
    
            % Generate all possible patterns
            for i = 1:length(tokenOrderList)
                for j = 1:length(sequenceList)
                    for k = 1:length(sequenceList)
                        if excludeCheckbox == 1 && j ~= k % so that not same files get compared 
                            sequencePattern = GenerateStream(app, sequenceList(k), sequenceList(j), tokenOrderList{i});
                            sequencePatternList{i, j, k} = sequencePattern;
                        elseif excludeCheckbox == 0
                            sequencePattern = GenerateStream(app, sequenceList(k), sequenceList(j), tokenOrderList{i});
                            sequencePatternList{i, j, k} = sequencePattern;
                        end
                    end
                end
            end

            % For ListBox: flatten ALL tokennames into 1D list
            allTokenNames = {};
            idx = 1;
            for i = 1:length(tokenOrderList)
                for j = 1:length(sequenceList)
                    for k = 1:length(sequenceList)
                        if excludeCheckbox == 1 && j ~= k % so that not same files get compared 
                            allTokenNames{idx} = sequencePatternList{i,j,k}.tokennames; % add the electrodes and amplitudes values 
                            idx = idx + 1;
                        elseif excludeCheckbox == 0
                            allTokenNames{idx} = sequencePatternList{i,j,k}.tokennames; % add the electrodes and amplitudes values 
                            idx = idx + 1;
                        end
                    end
                end
            end

            app.(listBoxName).Items = allTokenNames';  % Now shows ALL generated patterns

            app.Unified.stream = sequencePatternList{i, j, k};
            app.Unified.streamName = allTokenNames';
        end


    %     function GenLog(app)
    %           %record sequence played and buttonpress
    %                     trialnumber_Discrimination{runcounter_Discrimination} = runcounter_Discrimination;
    %                     DateandTime_Discrimination{runcounter_Discrimination} = datestr(datetime);
    %                     sequence_playorder_Discrimination{runcounter_Discrimination} = sequencenames2{i};
    %                     puttonpressed_Discrimination{runcounter_Discrimination} = value;
    % 
    %                     if app.Multielectrodes == 0
    %                         Amplitudechosen_Discrimination{runcounter_Discrimination} =  app.AmplitudeCLunitsEditField_A_2.Value;
    %                     else
    %                         Amplitudechosen1_Discrimination{runcounter_Discrimination} =  sequences2{1,i}.usedAmplitudes1;
    %                         Amplitudechosen2_Discrimination{runcounter_Discrimination} =  sequences2{1,i}.usedAmplitudes2;
    %                     end
    % 
    %                     if app.Electrode2DropDown.Value == '0' && app.Multielectrodes ==0
    %                         ElectrodeChosen_Discrimination{runcounter_Discrimination} = app.ElectrodeDropDown.Value;
    %                     end
    %                     if app.Electrode2DropDown.Value ~= '0' && app.Multielectrodes ==0
    %                         ElectrodeChosen_Discrimination{runcounter_Discrimination} = Electrodestoplay;
    %                     end
    %                     if app.Multielectrodes == 1
    %                         ElectrodeChosen1_Discrimination{runcounter_Discrimination} = sequences2{1,i}.usedElectrodes1;
    %                         ElectrodeChosen2_Discrimination{runcounter_Discrimination} = sequences2{1,i}.usedElectrodes2;
    %                     end
    % 
    %                     mode_chosen_Discrimination{runcounter_Discrimination} = app.modes;
    %                     seq_phase_chosen_Discrimination{runcounter_Discrimination} = app.StimphasesEditField_A_2.Value;
    %                     seq_ipg_chosen_Discrimination{runcounter_Discrimination} = app.InterphasegapsEditField_A_2.Value;
    %                     Timebetweensequencesms_Discrimination{runcounter_Discrimination} = app.intersequencemsEditField.Value;
    % 
    % 
    %                 if app.Multielectrodes == 0
    %                     Data = table(trialnumber_Discrimination',DateandTime_Discrimination',sequence_playorder_Discrimination',...
    %                         puttonpressed_Discrimination',Amplitudechosen_Discrimination',ElectrodeChosen_Discrimination',...
    %                         mode_chosen_Discrimination',seq_phase_chosen_Discrimination',seq_ipg_chosen_Discrimination',...
    %                         Timebetweensequencesms_Discrimination');
    %                     Data.Properties.VariableNames = {'Trial Number' 'Date & Time' 'Sequences presented'...
    %                         'Button Pressed' 'Amplitude' 'Electrode'...
    %                         'Mode' 'Phase' 'interphase gap'...
    %                         'Inter Sequence Interval'};
    %                 else
    %                     Data = table(trialnumber_Discrimination',DateandTime_Discrimination',sequence_playorder_Discrimination',...
    %                         puttonpressed_Discrimination',Amplitudechosen1_Discrimination',ElectrodeChosen1_Discrimination',...
    %                         Amplitudechosen2_Discrimination',ElectrodeChosen2_Discrimination',mode_chosen_Discrimination',...
    %                         seq_phase_chosen_Discrimination',seq_ipg_chosen_Discrimination',Timebetweensequencesms_Discrimination');
    %                     Data.Properties.VariableNames = {'Trial Number' 'Date & Time' 'Sequences presented'...
    %                         'Button Pressed' 'Amplitude 1' 'Electrode 1'...
    %                         'Amplitude 2' 'Electrode 2' 'Mode' ...
    %                         'Phase' 'interphase gap' 'Inter Sequence Interval'};
    %                 end
    %                 d=datestr(datetime);
    %                 runnumber = num2str(app.RunOfTheDay);
    %                 logfilename = strcat(current_Dislog_Directory,'\',app.PatientIDEditField.Value,'_',d(1:11),'_run_', num2str(n), '_V._', num2str(V),'.csv');
    %                 writetable(Data,logfilename);
    % 
    %                 final_data_to_save = Data; %%% CHANGED: Was results(1:runcounter, :), but results is already the correct size and runcounter (numSequences) is much smaller than rowCount (numTrials).
    %                 d = datestr(datetime);
    % 
    %                 short_sheet_name = ['Run_', num2str(n)];
    % 
    %                 allTables{end+1} = final_data_to_save; %%% Append the table
    %                 sheetNames{end+1} = short_sheet_name; %%% Append the valid sheet name
    %             end
    %         end
    %         %             catch
    %         %                 errordlg(strcat('Run failed but salvaged',{' '},num2str(runcounter-1),{' '},'trials'),'Error')
    %         %             end
    % 
    %         if app.Multielectrodes == 0
    %             Data = table(trialnumber_Discrimination',DateandTime_Discrimination',sequence_playorder_Discrimination',...
    %                 puttonpressed_Discrimination',Amplitudechosen_Discrimination',ElectrodeChosen_Discrimination',...
    %                 mode_chosen_Discrimination',seq_phase_chosen_Discrimination',seq_ipg_chosen_Discrimination',...
    %                 Timebetweensequencesms_Discrimination');
    %             Data.Properties.VariableNames = {'Trial Number' 'Date & Time' 'Sequences presented'...
    %                 'Button Pressed' 'Amplitude' 'Electrode'...
    %                 'Mode' 'Phase' 'interphase gap'...
    %                 'Inter Sequence Interval'};
    %         else
    %             Data = table(trialnumber_Discrimination',DateandTime_Discrimination',sequence_playorder_Discrimination',...
    %                 puttonpressed_Discrimination',Amplitudechosen1_Discrimination',ElectrodeChosen1_Discrimination',...
    %                 Amplitudechosen2_Discrimination',ElectrodeChosen2_Discrimination',mode_chosen_Discrimination',...
    %                 seq_phase_chosen_Discrimination',seq_ipg_chosen_Discrimination',Timebetweensequencesms_Discrimination');
    %             Data.Properties.VariableNames = {'Trial Number' 'Date & Time' 'Sequences presented'...
    %                 'Button Pressed' 'Amplitude 1' 'Electrode 1'...
    %                 'Amplitude 2' 'Electrode 2' 'Mode' ...
    %                 'Phase' 'interphase gap' 'Inter Sequence Interval'};
    %         end
    % 
    %         d=datestr(datetime);
    %         runnumber = num2str(app.RunOfTheDay);
    %         logfilename = strcat(current_Dislog_Directory,'\',app.PatientIDEditField.Value,'_',d(1:11),'_run_', num2str(n),'_V._', num2str(V),'.csv');
    %         writetable(Data,logfilename);
    % 
    % overall_logfilename = strcat(app.DisLogDirectory, '\', app.PatientIDEditField.Value,'_PitchDiscrimination_Overall_', d(1:11), '_Version_', num2str(V), '.xlsx');
    % 
    % num_tables_to_save = length(allTables); 
    % 
    % for k = 1:num_tables_to_save
    % currentTable = allTables{k};
    % currentSheet = sheetNames{k};
    % 
    % writetable(currentTable, overall_logfilename, 'Sheet', currentSheet);
    %     end
        
        
        % function DisRuntime(app)
    %         %% Check if runtime is too long      
    %         all_seq_electrodes = 0;
    % 
    %         for l = 1:length(sequences2)
    %             this_seq_electrodes = sequences2{1,l}.electrodes;
    %             all_seq_electrodes = all_seq_electrodes + length(this_seq_electrodes);
    %         end
    % 
    %         mid_seq_electrodes = all_seq_electrodes/length(sequences2);
    % 
    %         preStimulationTime = 3e5; %in micro s % 300000
    %         stimulationTime_inmicros = preStimulationTime + mid_seq_electrodes*app.frame_period; 
    %         stimulationTime_inmillis = stimulationTime_inmicros/1e3; % 1000
    %         stimulationTime = stimulationTime_inmillis/1e3; % in s
    %         buttonClick = 0.2; % estimated per trial
    % 
    %         disp(stimulationTime)
    % 
    %         timePerDecision = stimulationTime + buttonClick; % in s
    % 
    %         trialsperRun = length(sequences2);
    %         Runs = app.RunsEditField.Value;
    %         totalTrials = trialsperRun * Runs;
    % 
    %         maxRunTime = timePerDecision * totalTrials;
    % 
    %         if maxRunTime>60
    %             runtimestring = strcat(num2str(maxRunTime/60),{' '}, 'minutes');
    %         else
    %             runtimestring = strcat(num2str(maxRunTime),{' '}, 'seconds');
    %         end
    %     end
        
        function ToggleContinue(app, value, Experiment)
            % Toggle visibility of discrimination and continue UI groups
            
            % Define element groups
            if strcmp(Experiment, 'Discrimination')
                prefix = 'Discrimination';
            elseif strcmp(Experiment, 'Ranking')
                prefix = 'Ranking';
            else
                error('Unknown Experiment: %s', Experiment);
            end
            
            showGroup = [
                app.([prefix '_ContinueButton']), ...
                app.([prefix '_Table']), ...
                app.([prefix '_SaveTableButton']), ...
                app.([prefix '_ClearTable']), ...
                app.([prefix '_AddfilesButton']), ...
                app.([prefix '_TablenameLabel']), ...
                app.([prefix '_Plot']), ...
                app.([prefix '_ExcludeCatchTrialsCheckBox'])
            ];
            
            hideGroup = [
                app.([prefix '_RunButton']), ...
                app.([prefix '_ReturnButton']), ...
                app.([prefix '_ListBox']), ...
                app.([prefix '_SequencePatternListLabel']), ...
            ];

            % Default mode if no value provided
            if nargin < 2
                value = 'on';
            end
            
            % Use switch for clarity
            switch lower(value)
                case 'on'
                    setVisible(app, showGroup, 'on');
                    setVisible(app, hideGroup, 'off');
                case 'off'
                    setVisible(app, showGroup, 'off');
                    setVisible(app, hideGroup, 'on');
                otherwise
                    warning('Invalid input: value must be ''on'' or ''off''.');
            end
        end
        
        function setVisible(app, components, state)
            % Helper function to safely set visibility
            for c = components
                if isvalid(c)
                    c.Visible = state;
                end
            end
        end

        function RunLinearExperiment(app, Experiment )

            % app.Unified.sequencePatternFileName
            listBoxName = [Experiment '_ListBox'];
            streamsInListBox = ismember(app.Unified.sequencePatternFileName, app.(listBoxName).Items);
            [~, sequencePatternsToExclude] = ismember(app.(listBoxName).Value, app.(listBoxName).Items);
            streamsToUse = setdiff(1:length(streamsInListBox), sequencePatternsToExclude);
   
            for n = 1:app.RunsEditField.Value

                streamsToUse = streamsToUse(randperm(length(streamsToUse))); % shuffle for each run 

                for i = 1:lenght(streamsToUse)

                    streamName = app.Unified.sequencePatternFileName(streamsToUse);

                    if app.TransfertoNICCheckBox.Value == true, TransfertoNic(app, stream, streamName, Experiment); end
                    
                    QuestionFigure(app);

                    Question Figure(Filenames, Trialnum, Question, Buttons)

                    GenerateLog(app);   
                end
            end
        end

        function RunListExperiment(app)

        end

        
        function TransfertoNic(app, stream, streamName, Experiment) %unsure if works
            logDir = app.Directory + '\' + string(app.PatientIDEditField.Value) + '\' + Experiment;
            logfilename = logDir + '\' + string(streamName) + '.txt';
            
            % Create directory if it doesn't exist (creates parents automatically)
            mkdir(logDir);
            
            properties = java.util.HashMap;
            properties.put('platform', 'SP16'); % could change the platform
            properties.put('implant', 'CIC4'); % could change the implant
            properties.put('mode', app.ModeDropDown.Value);
            properties.put('min_pulse_width_us', '25.0'); % pulse width adjustaböe
            properties.put('go_live', 'off'); % go live 
            
            properties.put('log_stream_format', 'basic'); % 
            properties.put('log_stream_filename', char(logfilename));
            
            properties.put('auto_pufs', 'on');
            properties.put('verbose', 'on');
            properties.put('flagged_electrodes', app.FlagElectrodeCheckBoxValues);
            properties.put('latency_ms', '100');
            properties.put('c_levels_pulse_width_us', '25');
            properties.put('c_levels', '25');
            client = com.cochlear.nic.nic4.Streamer(properties);
            
            client.start();
            client.sendData(cochlear.nic.nic4.struct2map(stream));
            client.waitUntilFinished();
            client.stop();
            client.close();
        end
        

        function QuestionFigure(app, Experiment, TokenPattern, streamName)
            % QUESTIONFIGURE  Open a question/answer figure for the current trial
            %
            % Inputs:
            %   app            - App Designer app object.
            %   Experiment     - string, e.g., "Discrimination" or "Ranking".
            %   TokenPattern   - string, e.g., "A-B,A".
            %   streamName     - string, name of the stimulus stream to show.
        
            % Remove any existing figure with this tag, to avoid duplicates.
            existingFig = findall(groot, 'Tag', 'QuestionFigure');
            if ~isempty(existingFig)
                delete(existingFig);
            end
        
            % Create the figure window.
            fig = uifigure(...
                'Name',            [Experiment ' - Question'], ...
                'WindowStyle',     'normal', ...
                'Position',        [400 200 500 300], ...
                'Tag',            'QuestionFigure');
        
            % Top label: show streamName with line breaks after each token/sequence name.
            tokens = regexp(TokenPattern, '[A-Z]', 'match');   % {'A','B','A'}
            if ~isempty(tokens)
                sequenceNames = strjoin(tokens, '\newline');
            else
                sequenceNames = streamName;
            end
        
            questionLabel = uilabel(fig, ...
                'Text', [streamName '\newline' sequenceNames], ...
                'HorizontalAlignment', 'left', ...
                'Position', [40 220 400 40]);
        
            % Question text (dynamic based on Experiment).
            switch Experiment
                case 'Discrimination'
                    questionText = 'Which stimulus was different?';
                case 'Ranking'
                    questionText = 'Rank the stimuli in order of intensity (1 = strongest).';
                otherwise
                    questionText = 'Answer the question for this trial.';
            end
        
            uilabel(fig, ...
                'Text', questionText, ...
                'Position', [40 170 400 30]);
        
            % Run and trial info (placeholders; you fill app fields as needed).
            if isfield(app, 'runcount') && isfield(app, 'trialcount')
                infoText = sprintf('Run %d, Trial %d', app.runcount, app.trialcount+1);
            else
                infoText = 'Run / Trial info not available.';
            end
        
            uilabel(fig, ...
                'Text', infoText, ...
                'Position', [40 120 400 25]);
        
            % Dynamic answer buttons (example: left/right for discrimination).
            if strcmp(Experiment, 'Discrimination')
                buttonWidth  = 120;
                buttonHeight = 40;
                baselineY    = 60;
        
                b1 = uibutton(fig, 'push');
                b1.Text = 'Stimulus A';
                b1.Position = [80+0*(buttonWidth+10) baselineY buttonWidth buttonHeight];
        
                b2 = uibutton(fig, 'push');
                b2.Text = 'Stimulus B';
                b2.Position = [80+1*(buttonWidth+10) baselineY buttonWidth buttonHeight];
        
                % You can store callback in the button UserData or link to app callbacks.
                b1.ButtonPushedFcn = @(btn,event) app.onQuestionAnswer('A');
                b2.ButtonPushedFcn = @(btn,event) app.onQuestionAnswer('B');
        
            elseif strcmp(Experiment, 'Ranking')
                uilabel(fig, ...
                    'Text', 'Click in order of preference', ...
                    'Position', [40 60 400 25]);
                % Here you would add a ranking UI (e.g., numbered buttons, drag‑and‑drop, etc.).
            end
        
            % Make figure visible.
            fig.Visible = 'on';
        end
        
        function GenerateLog(app, stream, streamName, Experiment)

            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            setupMapValues(app, "Comfort", "ComfortValues");
            setupMapValues(app, "Threshold", "ThresholdValues");
            setupMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues");

            app.PatientID = app.PatientIDEditField.Value;

            app.startPath = fileparts(mfilename('fullpath'));
            app.DirectoryLabel.Text = "Directory: " + app.startPath;

            GenerateSeqTabStart(app);
            GenerateAppSettingsStart(app);

            app.TestSeq = struct();
            app.TestSeq.File = []; % generate as a struct with amplitudes sequences electrodes patterns logfilenames and folders 
            app.TestSeq.OldElectrodes = [];
            app.TestSeq.OldAmplitudes = [];

            app.Unified = struct();
            app.Unified.File = [];  
            app.Unified.MasterTable = table();

            app.Discrimination_Table.ColumnEditable = [false, true, true, false]; % initiate table settings
            app.Ranking_Table.ColumnEditable = [false, true, true, false];

            app.Discrimination_Table.ColumnName = string({'Filename', 'Electrodes', 'Amplitudes', 'Remove'});
            app.Ranking_Table.ColumnName = string({'Filename', 'Electrodes', 'Amplitudes', 'Remove'});

        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientID = value;
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)

            % Open the folder selection dialog
            folderName = uigetdir(app.startPath, 'Select the Directory');
            
            if isequal(folderName, 0)
                return; 
            end

            app.Directory = folderName;
            app.DirectoryLabel.Text = ["Directory:", app.Directory];
        end

        % Callback function
        function OversightTabGroupSelectionChanged(app, event)
            % to activate Log in just put this callback in the
            % selectionChangedFcn on app.OversightTabGroup
            if app.isLoginSuccess == 0 
                app.OversightTabGroup.SelectedTab = app.MainTab;
                errordlg('You must log in to access this tab','Error')
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            prompt = {'Enter Username:','Enter Password:'};
            dlgtitle = 'Log in';
            dims = [1 35];
            definput = {'',''};
            loginDetails = inputdlg(prompt,dlgtitle,dims,definput);

            userEntry = string(strtrim(loginDetails));
            administartorEntry = string(strtrim(app.administrator));

            if isempty(loginDetails), return; end

            if isequal(userEntry(:), administartorEntry(:)), app.isLoginSuccess = 1; app.LoginButton.Enable = false; app.LoginButton.Text = "Logged in"; end
        end

        % Value changed function: FlagunflagallElectrodesCheckBox
        function FlagunflagallElectrodesCheckBoxValueChanged(app, event)
            value = app.FlagunflagallElectrodesCheckBox.Value;

            app.FlagElectrodeCheckBoxValues(:) = value;

            for i = 1:length(app.FlagElectrodeCheckBoxValues)
                compName = sprintf('FlagElectrodeCheckBox_%d', i); 
                % Update the UI component directly
                app.(compName).Value = value;
            end
            
        end

        % Value changed function: PulsesperburstEditField
        function PulsesperburstEditFieldValueChanged(app, event)
            value = app.PulsesperburstEditField.Value;
            numIPGs = value - 1;
            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % biggest possbile wait for those many pulses

            if value > 1
                app.possibleFrames = app.RateEditField_inms.Value/app.frame_rate_millis;
                app.roundedpossibleFrames = floor(app.possibleFrames);

                app.InterpulsegapEditField.Enable = "on";
                app.InputgapsButton.Enable = "on";
                numIPG = app.PulsesperburstEditField.Value-1;
                if numIPG < 1, numIPG = 1; end
                app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield
                            
                [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
                ipgValue = middleNumofFrame;

                app.interpulsegaps = ones(1,numIPGs) * ipgValue; % newly initiates the Array

                app.InterpulsegapEditField.Value = round(ipgValue, 4);

                app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

                UpdateInterpulseGaps(app); % writes the IPG Label 
            else
                app.interpulsegaps = [];
                app.WaitPeriodEditField.Value = app.RateEditField_inms.Value;
                UpdateInterpulseGaps(app);
                app.InterpulsegapEditField.Enable = "off";
                app.InputgapsButton.Enable = "off";
            end

            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: RateEditField_inms
        function RateEditField_inmsValueChanged(app, event)
            % Calculate the nearest multiple
            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            
            % Update the UI so the user sees the actual value being used
            app.RateEditField_inms.Value = round(snapped_value, 4);
            
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

            UpdateMaxPulses(app);

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield
            
            [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
            ipgValue = middleNumofFrame;

            if ~isempty(app.interpulsegaps)
                app.interpulsegaps = ones(1,numIPG) * ipgValue;
                app.InterpulsegapEditField.Value = ipgValue;
                UpdateInterpulseGaps(app); % writes the IPG Label 
            end    

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % limit the waitperiod to the Rate 
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            ResetInterpulseGap(app);
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: RateEditField_inpps
        function RateEditField_inppsValueChanged(app, event)

            app.RateEditField_inms.Value = 1e3/app.RateEditField_inpps.Value; % get in ms 

            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            
            % Update the UI so the user sees the actual value being used
            app.RateEditField_inms.Value = round(snapped_value, 4);
            
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

           
            UpdateMaxPulses(app);

            [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
            ipgValue = middleNumofFrame;
            
            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield

            if ~isempty(app.interpulsegaps)
                app.interpulsegaps = ones(1,numIPG) * ipgValue;
                app.InterpulsegapEditField.Value = round(ipgValue, 4);
                UpdateInterpulseGaps(app); % writes the IPG Label 
            end % newly initiates the Array        
            

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % limit the waitperiod to the Rate 
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            ResetInterpulseGap(app);
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: InterpulsegapEditField
        function InterpulsegapEditFieldValueChanged(app, event)
            if app.InterpulsegapEditField.Value < app.frame_rate_millis, app.InterpulsegapEditField.Value = app.frame_rate_millis; end

            value = round(app.InterpulsegapEditField.Value / app.IPG.stepsize) * app.IPG.stepsize;

            app.InterpulsegapEditField.Value = round(value, 4); % should calc the next working multiple of app.framerate

            app.interpulsegaps(:) = app.InterpulsegapEditField.Value; % all of ipg array gets set to 

            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            UpdateInterpulseGaps(app); % writes the IPG Label 

            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Button pushed function: InputgapsButton
        function InputgapsButtonPushed(app, event)
            persistent Input

            numIPGs = app.PulsesperburstEditField.Value - 1;
            defaultInput = [];

            for i = 1:numIPGs
                defaultInput = strcat(defaultInput,num2str(app.InterpulsegapEditField.Value));
                if i<numIPGs, defaultInput = strcat(defaultInput,","); end
            end

            if isempty(Input) || app.IPG.isChanged
                app.IPG.isChanged = 0;
                Input = defaultInput;
            end
           
            prompt = 'Enter desired IPGs:';
            dlgtitle = 'Input multiple IPGs';
            dims = [1 170];
            answer = inputdlg(prompt,dlgtitle,dims,Input);

            userInput = str2num(answer{1}); 

            if length(userInput) ~= numIPGs
                return;
            end

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            maxIPG = app.frame_rate_millis*(floor(app.roundedpossibleFrames / numIPG) * numIPG)/(app.PulsesperburstEditField.Value-1); % interpulsegap Limit

            for i = 1:length(userInput)
                userInput(i) = round(userInput(i) / app.IPG.stepsize) * app.IPG.stepsize;
                if userInput(i) > maxIPG
                    userInput(i) = maxIPG;
                end
            end
            
            Input = join(string(userInput), ", ");   
          
            app.interpulsegaps = userInput;
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);
            UpdateInterpulseGaps(app); % writes the IPG Label 

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: WaitPeriodEditField
        function WaitPeriodEditFieldValueChanged(app, event)
            totalGap = app.RateEditField_inms.Value - app.WaitPeriodEditField.Value;
            numGaps = app.PulsesperburstEditField.Value - 1;
            rawGap = totalGap / numGaps;
          
 
            if numGaps > 0
                snappedIpg = round(rawGap / app.IPG.stepsize) * app.IPG.stepsize;
    
                numIPG = app.PulsesperburstEditField.Value-1;
                if numIPG < 1, numIPG = 1; end
                maxIPG = app.frame_rate_millis*(floor(app.roundedpossibleFrames / numIPG) * numIPG)/(app.PulsesperburstEditField.Value-1); % interpulsegap Limit
    
                if snappedIpg > maxIPG, snappedIpg = maxIPG; end

            else
                % Single pulse logic: Gap is effectively zero or N/A
                snappedIpg = 0;
            end

            app.InterpulsegapEditField.Value = round(snappedIpg, 4); 
            app.interpulsegaps(:) = snappedIpg; % all of ipg array gets set to 
            

            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value - (snappedIpg * numGaps), 4);

            UpdateInterpulseGaps(app); % writes the IPG Label 
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            
            mainfolder = app.Directory;
            subfolder = app.brustsfolder;

            specifiedfolder = fullfile(mainfolder, subfolder);

            if ~exist(specifiedfolder, "dir")
                mkdir(specifiedfolder)
            end

            burst_struct = GenerateBurstStruct(app);
            defaultLocation = fullfile(specifiedfolder, strcat(app.FilenameEditField.Value, '.mat'));
            
            % 1. Open the dialog
            [file, folder] = uiputfile(defaultLocation, 'Save Burst Structure');
            
            % 2. Check if the user actually selected a file (did not click cancel)
            if ischar(file) || isstring(file)
                % 3. Use fullfile for cross-platform compatibility (Windows/Mac/Linux)
                fullSavePath = fullfile(folder, file);
                
                % 4. Save the variable
                save(fullSavePath, 'burst_struct');
                
                % Optional: Provide feedback to the user
                fprintf('Burst_struct file saved successfully: %s\n', fullSavePath);
            else
                % User clicked cancel
                disp('Save cancelled by user.');
            end
        end

        % Value changed function: ZoomfactorSpinner
        function ZoomfactorSpinnerValueChanged(app, event)
            GenerateSequenceZoomedPlot(app);
        end

        % Button pushed function: resetMapButton
        function resetMapButtonPushed(app, event)
            resetMapValues(app, "Comfort", "ComfortValues");
            resetMapValues(app, "Threshold", "ThresholdValues");
            resetMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues")
        end

        % Button pushed function: resetValuesButton
        function resetValuesButtonPushed(app, event)
            resetMapValues(app, "Comfort", "ComfortValues");
            resetMapValues(app, "Threshold", "ThresholdValues");
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;

            modeMap = containers.Map({'MP1','MP2','MP1+2'}, [-1, -2, -3]);
            if isKey(modeMap, value)
                app.modes = modeMap(value);
            end

        end

        % Value changed function: CalculateasDynamicRangeofMapCheckBox
        function CalculateasDynamicRangeofMapCheckBoxValueChanged(app, event)
            value = app.CalculateasDynamicRangeofMapCheckBox.Value;
            app.isactiveDynamicRange = value;

            % set limit for all amplitude buttons up to 255 if unchecked 
            % set limit to 1 if checked 
            % set and update all amplitude values and limits 
        end

        % Value changed function: TestSeq_Electrodes
        function TestSeq_ElectrodesValueChanged(app, event)
        
        if ~isempty(app.TestSeq.File) && isfield(app.TestSeq.File, 'burst_struct')

            [Electrodes, OldElectrodes] = ElectrodesValueChanged(app, app.TestSeq.File, app.TestSeq_Electrodes.Value, app.TestSeq.OldElectrodes);
           
            app.TestSeq_Electrodes.Value = Electrodes;
            app.TestSeq.OldElectrodes = OldElectrodes;

            for i = 1:length(app.TestSeq.File)
                GenerateTestSeq(app, app.TestSeq.File(i));
            end
            % should also work for single input without muliplying 
            % also check issue with if 1 is flagged 
        end
        end

        % Button pushed function: PickFilesButton
        function PickFilesButtonPushed(app, event)
            persistent specifiedfolder
            
            mainfolder = app.Directory;
            subfolder = app.brustsfolder;

            if isempty(specifiedfolder)
                specifiedfolder = fullfile(mainfolder, subfolder);
            end
            
            [fileNames, pathName] = uigetfile( ...
                {'*.mat','MAT files (*.mat)'}, ...
                'Select Multiple Files', ...
                'MultiSelect', 'on' , specifiedfolder);

            % Handle cancel
            if isequal(fileNames, 0)
                return;
            end

            % Process files
            if iscell(fileNames)  % Multiple files
                burstFile = struct('data', {}, 'filename', {});  % Preallocate struct array
                for i = 1:length(fileNames)
                    fullPath = fullfile(pathName, fileNames{i}); % Process fullPath
                    fprintf('Loading: %s\n', fullPath);
                    burstFile(i).data = load(fullPath);  % Data structure
                    % burstFile(i).filename = fileNames{i};
                [~, burstFile(i).filename, ~] = fileparts(fileNames{i}); % remove file extensions

                    SetTestSeqInputs(app, "off",  app.TestSeq.File) % will cause error bcs of no file 
                end
            else  % Single file (still returned as char, not cell)
                fullPath = fullfile(pathName, fileNames); % Process fullPath
                fprintf('Loading: %s\n', fullPath);
                burstFile(1).data = load(fullPath);  % Data structure
                % burstFile(1).filename = fileNames{i};
                [~, burstFile(1).filename, ~] = fileparts(fileNames{1}); % remove file extensions

                app.TestSeq_Filename.Text = "File: " +string(fileNames);
                app.TestSeq.File = burstFile(1).data; % for now to check electrodes

                app.TestSeq.Filename = {burstFile.filename};

                numPulses = nnz(burstFile(1).data.burst_struct);
                electrodesList = str2double(split(app.TestSeq_Electrodes.Value, ','));
                amplitudesList = str2double(split(app.TestSeq_Amplitudes.Value, ','));

                SetTestSeqInputs(app, "on", app.TestSeq.File)

                if electrodesList ~= numPulses
                    for i = 1:length(app.TestSeq.File)
                        electrodesDisplay = SetElectrodesInput(app, app.TestSeq.File(i));
                        app.TestSeq_Electrodes.Value = electrodesDisplay;
                    end
                end
                if amplitudesList ~= numPulses
                    for i = 1:length(app.TestSeq.File)
                        amplitudesDisplay = SetAmplitudesInput(app, app.TestSeq.File(i));
                        app.TestSeq_Amplitudes.Value = amplitudesDisplay;
                    end
                end
                
                GenerateTestSeq(app, app.TestSeq.File(1));
            end

            % AFTER loop: Set unified properties ONCE with ALL files
            app.Unified.File = burstFile;              % Full array [file1 file2 file3...]
            app.Unified.Filename = {burstFile.filename};   % Cell array of ALL filenames
            
            % Now populate tables with ALL files
            UpdateTableNameLabels(app, "No name specified");
            InitiateFileTable(app, app.Unified.File);  % BOTH tables populate instantly

            specifiedfolder = pathName;  % Remember for next time
        end

        % Value changed function: TransfertoNICCheckBox
        function TransfertoNICCheckBoxValueChanged(app, event)
            value = app.TransfertoNICCheckBox.Value;
            app.isactiveTransfer = value;
        end

        % Value changed function: RampingCheckBox
        function RampingCheckBoxValueChanged(app, event)
            value = app.RampingCheckBox.Value;
            app.isactiveRamping = value;

            if app.isactiveRamping
                app.RampingLength_inmsEditField.Enable = 'on';
            else
                app.RampingLength_inmsEditField.Enable = 'off';
            end
        end

        % Value changed function: GenerateLogCheckBox
        function GenerateLogCheckBoxValueChanged(app, event)
            value = app.GenerateLogCheckBox.Value;
            app.isactiveLogging = value;
        end

        % Value changed function: TestSeq_Amplitudes
        function TestSeq_AmplitudesValueChanged(app, event)
            if ~isempty(app.TestSeq.File) && isfield(app.TestSeq.File, 'burst_struct')
                [Amplitudes, OldAmplitudes] = AmplitudesValueChanged(app, app.TestSeq.File, app.TestSeq_Electrodes.Value, app.TestSeq_Amplitudes.Value, app.TestSeq.OldAmplitudes);
                
                app.TestSeq_Amplitudes.Value = Amplitudes;
                app.TestSeq.OldAmplitudes = OldAmplitudes;
            
            for i = 1:length(app.TestSeq.File)
                GenerateTestSeq(app, app.TestSeq.File(i));
            end
            end
        end

        % Button pushed function: TestSeq_ResetElectrodes
        function TestSeq_ResetElectrodesButtonPushed(app, event)
            if ~isempty(app.TestSeq.File)
                electrodeDisplay = SetElectrodesInput(app, app.TestSeq.File);
                app.TestSeq_Electrodes.Value = electrodeDisplay;
            else
                app.TestSeq_Electrodes.Value = app.Initiate_Electrodes.Value; 
            end
        end

        % Button pushed function: Testseq_ResetAmplitudes
        function Testseq_ResetAmplitudesButtonPushed(app, event)
            if ~isempty(app.TestSeq.File)
                amplitudesDisplay = SetAmplitudesInput(app, app.TestSeq.File);
                app.TestSeq_Amplitudes.Value = amplitudesDisplay;
            else
                app.TestSeq_Amplitudes.Value = app.Initiate_Amplitudes.Value; 
            end
        end

        % Cell edit callback: Discrimination_Table
        function Discrimination_TableCellEdit(app, event)
            
            CellEditCallback(app, event, "Discrimination");

            % Update unified source
            app.Unified.MasterTable = app.Discrimination_Table.Data;
            % Auto-sync other table
            app.Ranking_Table.Data = app.Unified.MasterTable;
        end

        % Cell selection callback: Discrimination_Table
        function Discrimination_TableCellSelection(app, event)

            CellSelectionCallback(app, event, "Discrimination")

            % Update unified source
            app.Unified.MasterTable = app.Discrimination_Table.Data;
            % Auto-sync other table
            app.Ranking_Table.Data = app.Unified.MasterTable;
        end

        % Cell edit callback: Ranking_Table
        function Ranking_TableCellEdit(app, event)
            
            CellEditCallback(app, event, "Ranking")
            
            % Update unified source
            app.Unified.MasterTable = app.Ranking_Table.Data;
            % Auto-sync other table
            app.Discrimination_Table.Data = app.Unified.MasterTable;
        end

        % Cell selection callback: Ranking_Table
        function Ranking_TableCellSelection(app, event)
            
            CellSelectionCallback(app, event, "Ranking")

            % Update unified source
            app.Unified.MasterTable = app.Ranking_Table.Data;
            % Auto-sync other table
            app.Discrimination_Table.Data = app.Unified.MasterTable;
        end

        % Value changed function: AllTokenOrdersCheckBox
        function AllTokenOrdersCheckBoxValueChanged(app, event)
            value = app.AllTokenOrdersCheckBox.Value;
            
            if value
                app.TokenOrderDropDown.Enable = 'off';
            else
                app.TokenOrderDropDown.Enable = 'on';
            end
        end

        % Button pushed function: Discrimination_ClearTable
        function Discrimination_ClearTableButtonPushed(app, event)
            ClearTable(app)
        end

        % Button pushed function: Discrimination_AddfilesButton
        function Discrimination_AddfilesButtonPushed(app, event)
            AddFilesToTable(app)
        end

        % Button pushed function: Ranking_AddfilesButton
        function Ranking_AddfilesButtonPushed(app, event)
            AddFilesToTable(app)
        end

        % Button pushed function: Ranking_ClearTable
        function Ranking_ClearTableButtonPushed(app, event)
            ClearTable(app)
        end

        % Button pushed function: Discrimination_SaveTableButton
        function Discrimination_SaveTableButtonPushed(app, event)
            SaveTableData(app, app.Unified.MasterTable, app.Unified.Filename,...
                app.Unified.File, app.Unified.Electrodes, app.Unified.Amplitudes,...
                app.Unified.OldElectrodes, app.Unified.OldAmplitudes); % Oldele and amp are the last working inputs 
        end

        % Button pushed function: Ranking_SaveTableButton
        function Ranking_SaveTableButtonPushed(app, event)
            SaveTableData(app, app.Unified.MasterTable, app.Unified.Filename,...
                app.Unified.File, app.Unified.Electrodes, app.Unified.Amplitudes,...
                app.Unified.OldElectrodes, app.Unified.OldAmplitudes);
        end

        % Button pushed function: PickTableButton
        function PickTableButtonPushed(app, event)
            persistent specifiedfolder
            
            mainfolder = app.Directory;
            subfolder = app.tablesfolder;
            if isempty(specifiedfolder)
                specifiedfolder = fullfile(mainfolder, subfolder);
            end
            
            [fileName, pathName] = uigetfile( ...
                {'*.mat','Table MAT files (*.mat)'}, ...
                'Pick Saved Table', ...
                specifiedfolder);
            
            if isequal(fileName, 0)
                return;
            end
            
            fullPath = fullfile(pathName, fileName);
            fprintf('Loading table: %s\n', fullPath);
            
            % Load the saved table data
            loadData = load(fullPath);
            SaveData = loadData.SaveData;  % Extract from loaded struct

            app.Unified.MasterTable = SaveData.Table;
            app.Unified.Filename = {};
            app.Unified.File = {};
            app.Unified.Electrodes = {};
            app.Unified.Amplitudes = {};
            app.Unified.OldElectrodes = {};
            app.Unified.OldAmplitudes = {};
            app.Unified.Sequence = struct();

            % Restore to app.Unified
            % app.Unified.MasterTable = SaveData.Table;
            app.Unified.Filename = SaveData.Filenames;
            app.Unified.File = SaveData.Files;
            app.Unified.Electrodes = SaveData.Electrodes;
            app.Unified.Amplitudes = SaveData.Amplitudes;
            app.Unified.OldElectrodes = SaveData.OldElectrodes;
            app.Unified.OldAmplitudes = SaveData.OldAmplitudes;
            
            if ~isempty(app.Unified.File)
                firstSeq = genereateSequence(app, app.Unified.File(1).data, app.Unified.Filename{1}, ...
                                            app.Unified.Electrodes{1}, app.Unified.Amplitudes{1});
                app.Unified.Sequence = repmat(firstSeq, length(app.Unified.File), 1);

                for i = 1:length(app.Unified.File)
                    sequence = genereateSequence(app, app.Unified.File(i).data, app.Unified.Filename{i},  app.Unified.Electrodes{i}, app.Unified.Amplitudes{i});
                    app.Unified.Sequence(i) = sequence; % generate a first Sequence for all the files on the table 
                end

            end

            % Update UI
            cla(app.Discrimination_Plot);
            cla(app.Ranking_Plot);

            UpdateTableNameLabels(app, SaveData.TableName);
            UpdateUnifiedTables(app);

            app.Discrimination_ContinueButton.Enable = "on";
            app.Ranking_RunButton.Enable = "on";
            app.Ranking_ContinueButton.Enable = "on";
            
            specifiedfolder = pathName;
        end

        % Value changed function: TokenPatternDropDown
        function TokenPatternDropDownValueChanged(app, event)
            value = app.TokenPatternDropDown.Value;
            SetTokenOrder(app, value);
        end

        % Button pushed function: Ranking_RunButton
        function Ranking_RunButtonPushed(app, event)
            
        end

        % Button pushed function: Discrimination_RunButton
        function Discrimination_RunButtonPushed(app, event)
            % Run actual exp 
            send to Nic 
            Log 

            % DiscriminationExpperiment(app);
        end

        % Button pushed function: Discrimination_ContinueButton
        function Discrimination_ContinueButtonPushed(app, event)
            % set up listbox and activate the pick to exclude 
            ToggleContinue(app, 'off', 'Discrimination');
            SetUpListBox(app, 'Discrimination');
        end

        % Button pushed function: Discrimination_ReturnButton
        function Discrimination_ReturnButtonPushed(app, event)
            ToggleContinue(app, 'on', 'Discrimination');
        end

        % Button pushed function: TestSeq_Run
        function TestSeq_RunButtonPushed(app, event)
            sequence = struct(...
                'electrodes', app.TestSeq.Electrodes,...
                'modes',app.modes,...
                'current_levels',app.TestSeq.Amplitudes,...
                'phase_widths',25,...
                'phase_gaps',7, ...
                'periods',app.frame_rate_millis);

            Tab = "TestSeq";

            Filename = app.TestSeq.Filename;

            SendtoNic(app, sequence, Filename, Tab);
        end

        % Value changed function: Ranking_ExperimentDropDown
        function Ranking_ExperimentDropDownValueChanged(app, event)
            value = app.Ranking_ExperimentDropDown.Value;
            if value == "Matrix"
                app.Ranking_ExcludeCatchTrialsCheckBox.Visible = "on";
                app.Ranking_RunButton.Visible = "off";
                app.Ranking_ContinueButton.Visible = "on";
            else
                app.Ranking_ExcludeCatchTrialsCheckBox.Visible = "off";
                app.Ranking_RunButton.Visible = "on";
                app.Ranking_ContinueButton.Visible = "off";
            end
        end

        % Button pushed function: Ranking_ContinueButton
        function Ranking_ContinueButtonPushed(app, event)
            ToggleContinue(app, 'off', 'Ranking');
            SetUpListBox(app, 'Ranking');
        end

        % Button pushed function: Ranking_ReturnButton
        function Ranking_ReturnButtonPushed(app, event)
            ToggleContinue(app, 'on', 'Ranking');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OversightTabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.OversightTabGroup);
            app.MainTab.Title = 'Main';
            app.PatientIDEditFieldLabel = uilabel(app.MainTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.MainTab, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.PatientIDEditField.Value = 'insert_ID';
            app.ExperimentTabGroup = uitabgroup(app.MainTab);
            app.TestSequenceTab = uitab(app.ExperimentTabGroup);
            app.TestSequenceTab.Title = 'Test Sequence';
            app.TestSeq_Plot = uiaxes(app.TestSequenceTab);
            title(app.TestSeq_Plot, 'Sequence')
            xlabel(app.TestSeq_Plot, 'Time [ms]')
            ylabel(app.TestSeq_Plot, 'Amplitude [CL]')
            app.TestSeq_Filename = uilabel(app.TestSequenceTab);
            app.TestSeq_Filename.Text = 'File: ';
            app.TestSeq_Run = uibutton(app.TestSequenceTab, 'push');
            app.TestSeq_Run.ButtonPushedFcn = createCallbackFcn(app, @TestSeq_RunButtonPushed, true);
            app.TestSeq_Run.Enable = 'off';
            app.TestSeq_Run.Text = 'Run';
            app.TestSeq_Electrodes = uieditfield(app.TestSequenceTab, 'text');
            app.TestSeq_Electrodes.ValueChangedFcn = createCallbackFcn(app, @TestSeq_ElectrodesValueChanged, true);
            app.TestSeq_Electrodes.Enable = 'off';
            app.TestSeq_Electrodes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.TestSeq_Electrodes.Value = '1';
            app.InterpulsegapmsLabel_2 = uilabel(app.TestSequenceTab);
            app.InterpulsegapmsLabel_2.Text = 'Electrodes';
            app.TestSeq_Amplitudes = uieditfield(app.TestSequenceTab, 'text');
            app.TestSeq_Amplitudes.ValueChangedFcn = createCallbackFcn(app, @TestSeq_AmplitudesValueChanged, true);
            app.TestSeq_Amplitudes.Enable = 'off';
            app.TestSeq_Amplitudes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.TestSeq_Amplitudes.Value = '0.8';
            app.InterpulsegapmsLabel_3 = uilabel(app.TestSequenceTab);
            app.InterpulsegapmsLabel_3.Text = 'Amplitudes';
            app.TestSeq_ResetElectrodes = uibutton(app.TestSequenceTab, 'push');
            app.TestSeq_ResetElectrodes.ButtonPushedFcn = createCallbackFcn(app, @TestSeq_ResetElectrodesButtonPushed, true);
            app.TestSeq_ResetElectrodes.Enable = 'off';
            app.TestSeq_ResetElectrodes.Text = 'reset';
            app.Testseq_ResetAmplitudes = uibutton(app.TestSequenceTab, 'push');
            app.Testseq_ResetAmplitudes.ButtonPushedFcn = createCallbackFcn(app, @Testseq_ResetAmplitudesButtonPushed, true);
            app.Testseq_ResetAmplitudes.Enable = 'off';
            app.Testseq_ResetAmplitudes.Text = 'reset';
            app.TestSeq_PulsesLabel = uilabel(app.TestSequenceTab);
            app.TestSeq_PulsesLabel.Text = 'Pulses in Burst File: ';
            app.DiscrimationExpTab = uitab(app.ExperimentTabGroup);
            app.DiscrimationExpTab.Title = 'Discrimation Exp';
            app.Discrimination_Plot = uiaxes(app.DiscrimationExpTab);
            title(app.Discrimination_Plot, 'Sequence')
            xlabel(app.Discrimination_Plot, 'Time [ms]')
            ylabel(app.Discrimination_Plot, 'Amplitude [CL]')
            app.Discrimination_ListBox = uilistbox(app.DiscrimationExpTab);
            app.Discrimination_ListBox.Multiselect = 'on';
            app.Discrimination_ListBox.Visible = 'off';
            app.Discrimination_ListBox.Value = {'Item 1'};
            app.Discrimination_ContinueButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @Discrimination_ContinueButtonPushed, true);
            app.Discrimination_ContinueButton.Enable = 'off';
            app.Discrimination_ContinueButton.Text = 'Continue';
            app.Discrimination_AddfilesButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @Discrimination_AddfilesButtonPushed, true);
            app.Discrimination_AddfilesButton.Text = 'Add files';
            app.Discrimination_RunButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_RunButton.ButtonPushedFcn = createCallbackFcn(app, @Discrimination_RunButtonPushed, true);
            app.Discrimination_RunButton.Visible = 'off';
            app.Discrimination_RunButton.Text = 'Run';
            app.RunsLabel_2 = uilabel(app.DiscrimationExpTab);
            app.RunsLabel_2.Text = 'Runs:';
            app.Discrimination_RunsEditField = uieditfield(app.DiscrimationExpTab, 'text');
            app.Discrimination_RunsEditField.Value = '1';
            app.Discrimination_RuntimeLabel = uilabel(app.DiscrimationExpTab);
            app.Discrimination_RuntimeLabel.Visible = 'off';
            app.Discrimination_RuntimeLabel.Text = 'Estimated Runtime:';
            app.Discrimination_Table = uitable(app.DiscrimationExpTab);
            app.Discrimination_Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Discrimination_Table.RowName = {};
            app.Discrimination_Table.CellEditCallback = createCallbackFcn(app, @Discrimination_TableCellEdit, true);
            app.Discrimination_Table.CellSelectionCallback = createCallbackFcn(app, @Discrimination_TableCellSelection, true);
            app.Discrimination_ReturnButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @Discrimination_ReturnButtonPushed, true);
            app.Discrimination_ReturnButton.Visible = 'off';
            app.Discrimination_ReturnButton.Text = 'Return';
            app.Discrimination_SaveTableButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @Discrimination_SaveTableButtonPushed, true);
            app.Discrimination_SaveTableButton.Text = 'Save Table';
            app.Discrimination_TablenameLabel = uilabel(app.DiscrimationExpTab);
            app.Discrimination_TablenameLabel.Text = 'Table:';
            app.Discrimination_ClearTable = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_ClearTable.ButtonPushedFcn = createCallbackFcn(app, @Discrimination_ClearTableButtonPushed, true);
            app.Discrimination_ClearTable.Text = 'Clear Table';
            app.Discrimination_SequencePatternListLabel = uilabel(app.DiscrimationExpTab);
            app.Discrimination_SequencePatternListLabel.Visible = 'off';
            app.Discrimination_SequencePatternListLabel.Text = 'Select Files that will be excluded';
            app.Discrimination_ExcludeCatchTrialsCheckBox = uicheckbox(app.DiscrimationExpTab);
            app.Discrimination_ExcludeCatchTrialsCheckBox.Text = 'Exclude Catch Trials';
            app.RankingExpTab = uitab(app.ExperimentTabGroup);
            app.RankingExpTab.Title = 'Ranking Exp';
            app.Ranking_Plot = uiaxes(app.RankingExpTab);
            title(app.Ranking_Plot, 'Sequence')
            xlabel(app.Ranking_Plot, 'Time [ms]')
            ylabel(app.Ranking_Plot, 'Amplitude [CL]')
            app.Ranking_RunButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_RunButton.ButtonPushedFcn = createCallbackFcn(app, @Ranking_RunButtonPushed, true);
            app.Ranking_RunButton.Enable = 'off';
            app.Ranking_RunButton.Visible = 'off';
            app.Ranking_RunButton.Text = 'Run';
            app.RunsLabel = uilabel(app.RankingExpTab);
            app.RunsLabel.Text = 'Runs:';
            app.Ranking_RunsEditField = uieditfield(app.RankingExpTab, 'text');
            app.Ranking_RunsEditField.Value = '1';
            app.Ranking_AddfilesButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @Ranking_AddfilesButtonPushed, true);
            app.Ranking_AddfilesButton.Text = 'Add files';
            app.Ranking_Table = uitable(app.RankingExpTab);
            app.Ranking_Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Ranking_Table.RowName = {};
            app.Ranking_Table.CellEditCallback = createCallbackFcn(app, @Ranking_TableCellEdit, true);
            app.Ranking_Table.CellSelectionCallback = createCallbackFcn(app, @Ranking_TableCellSelection, true);
            app.Ranking_RuntimeLabel = uilabel(app.RankingExpTab);
            app.Ranking_RuntimeLabel.Visible = 'off';
            app.Ranking_RuntimeLabel.Text = 'Estimated Runtime:';
            app.Ranking_SaveTableButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @Ranking_SaveTableButtonPushed, true);
            app.Ranking_SaveTableButton.Text = 'Save Table';
            app.Ranking_TablenameLabel = uilabel(app.RankingExpTab);
            app.Ranking_TablenameLabel.Text = 'Table:';
            app.Ranking_ClearTable = uibutton(app.RankingExpTab, 'push');
            app.Ranking_ClearTable.ButtonPushedFcn = createCallbackFcn(app, @Ranking_ClearTableButtonPushed, true);
            app.Ranking_ClearTable.Text = 'Clear Table';
            app.Ranking_ExcludeCatchTrialsCheckBox = uicheckbox(app.RankingExpTab);
            app.Ranking_ExcludeCatchTrialsCheckBox.Text = 'Exclude Catch Trials';
            app.Ranking_ExcludeCatchTrialsCheckBox.Value = true;
            app.Ranking_ExperimentDropDown = uidropdown(app.RankingExpTab);
            app.Ranking_ExperimentDropDown.Items = {'Matrix', 'List'};
            app.Ranking_ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @Ranking_ExperimentDropDownValueChanged, true);
            app.Ranking_ExperimentDropDown.Value = 'Matrix';
            app.Ranking_ContinueButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @Ranking_ContinueButtonPushed, true);
            app.Ranking_ContinueButton.Enable = 'off';
            app.Ranking_ContinueButton.Text = 'Continue';
            app.Ranking_ReturnButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @Ranking_ReturnButtonPushed, true);
            app.Ranking_ReturnButton.Visible = 'off';
            app.Ranking_ReturnButton.Text = 'Return';
            app.Ranking_ListBox = uilistbox(app.RankingExpTab);
            app.Ranking_ListBox.Multiselect = 'on';
            app.Ranking_ListBox.Visible = 'off';
            app.Ranking_ListBox.Value = {'Item 1'};
            app.Ranking_SequencePatternListLabel = uilabel(app.RankingExpTab);
            app.Ranking_SequencePatternListLabel.Visible = 'off';
            app.Ranking_SequencePatternListLabel.Text = 'Select Files that will be excluded';
            app.Threshold_1 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_1.Limits = [0 255];
            app.Threshold_2 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_2.Limits = [0 255];
            app.Threshold_3 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_3.Limits = [0 255];
            app.Threshold_4 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_4.Limits = [0 255];
            app.Threshold_5 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_5.Limits = [0 255];
            app.Threshold_6 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_6.Limits = [0 255];
            app.Threshold_7 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_7.Limits = [0 255];
            app.Threshold_8 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_8.Limits = [0 255];
            app.Threshold_9 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_9.Limits = [0 255];
            app.Threshold_10 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_10.Limits = [0 255];
            app.Threshold_11 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_11.Limits = [0 255];
            app.Threshold_12 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_12.Limits = [0 255];
            app.Threshold_13 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_13.Limits = [0 255];
            app.Threshold_14 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_14.Limits = [0 255];
            app.Threshold_15 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_15.Limits = [0 255];
            app.Threshold_16 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_16.Limits = [0 255];
            app.Threshold_17 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_17.Limits = [0 255];
            app.Threshold_18 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_18.Limits = [0 255];
            app.Threshold_19 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_19.Limits = [0 255];
            app.Threshold_20 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_20.Limits = [0 255];
            app.Threshold_21 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_21.Limits = [0 255];
            app.Threshold_22 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_22.Limits = [0 255];
            app.Comfort_1 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_1.Limits = [0 255];
            app.Comfort_1.Value = 255;
            app.Comfort_2 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_2.Limits = [0 255];
            app.Comfort_2.Value = 255;
            app.Comfort_3 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_3.Limits = [0 255];
            app.Comfort_3.Value = 255;
            app.Comfort_4 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_4.Limits = [0 255];
            app.Comfort_4.Value = 255;
            app.Comfort_5 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_5.Limits = [0 255];
            app.Comfort_5.Value = 255;
            app.Comfort_6 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_6.Limits = [0 255];
            app.Comfort_6.Value = 255;
            app.Comfort_7 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_7.Limits = [0 255];
            app.Comfort_7.Value = 255;
            app.Comfort_8 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_8.Limits = [0 255];
            app.Comfort_8.Value = 255;
            app.Comfort_9 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_9.Limits = [0 255];
            app.Comfort_9.Value = 255;
            app.Comfort_10 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_10.Limits = [0 255];
            app.Comfort_10.Value = 255;
            app.Comfort_11 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_11.Limits = [0 255];
            app.Comfort_11.Value = 255;
            app.Comfort_12 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_12.Limits = [0 255];
            app.Comfort_12.Value = 255;
            app.Comfort_13 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_13.Limits = [0 255];
            app.Comfort_13.Value = 255;
            app.Comfort_14 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_14.Limits = [0 255];
            app.Comfort_14.Value = 255;
            app.Comfort_15 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_15.Limits = [0 255];
            app.Comfort_15.Value = 255;
            app.Comfort_16 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_16.Limits = [0 255];
            app.Comfort_16.Value = 255;
            app.Comfort_17 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_17.Limits = [0 255];
            app.Comfort_17.Value = 255;
            app.Comfort_18 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_18.Limits = [0 255];
            app.Comfort_18.Value = 255;
            app.Comfort_19 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_19.Limits = [0 255];
            app.Comfort_19.Value = 255;
            app.Comfort_20 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_20.Limits = [0 255];
            app.Comfort_20.Value = 255;
            app.Comfort_21 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_21.Limits = [0 255];
            app.Comfort_21.Value = 255;
            app.Comfort_22 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_22.Limits = [0 255];
            app.Comfort_22.Value = 255;
            app.ThresholdLabel = uilabel(app.MainTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.ComfortLabel = uilabel(app.MainTab);
            app.ComfortLabel.Text = 'Comfort';
            app.FlaggedLabel = uilabel(app.MainTab);
            app.FlaggedLabel.Text = 'Flagged';
            app.FlagElectrodeCheckBox_1 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_1.Text = '1';
            app.FlagElectrodeCheckBox_2 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_2.Text = '2';
            app.FlagElectrodeCheckBox_3 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_3.Text = '3';
            app.FlagElectrodeCheckBox_4 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_4.Text = '4';
            app.FlagElectrodeCheckBox_5 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_5.Text = '5';
            app.FlagElectrodeCheckBox_6 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_6.Text = '6';
            app.FlagElectrodeCheckBox_7 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_7.Text = '7';
            app.FlagElectrodeCheckBox_8 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_8.Text = '8';
            app.FlagElectrodeCheckBox_9 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_9.Text = '9';
            app.FlagElectrodeCheckBox_10 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_10.Text = '10';
            app.FlagElectrodeCheckBox_11 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_11.Text = '11';
            app.FlagElectrodeCheckBox_12 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_12.Text = '12';
            app.FlagElectrodeCheckBox_13 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_13.Text = '13';
            app.FlagElectrodeCheckBox_14 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_14.Text = '14';
            app.FlagElectrodeCheckBox_15 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_15.Text = '15';
            app.FlagElectrodeCheckBox_16 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_16.Text = '16';
            app.FlagElectrodeCheckBox_17 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_17.Text = '17';
            app.FlagElectrodeCheckBox_18 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_18.Text = '18';
            app.FlagElectrodeCheckBox_19 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_19.Text = '19';
            app.FlagElectrodeCheckBox_20 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_20.Text = '20';
            app.FlagElectrodeCheckBox_21 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_21.Text = '21';
            app.FlagElectrodeCheckBox_22 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_22.Text = '22';
            app.FlagunflagallElectrodesCheckBox = uicheckbox(app.MainTab);
            app.FlagunflagallElectrodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlagunflagallElectrodesCheckBoxValueChanged, true);
            app.FlagunflagallElectrodesCheckBox.Text = 'Flag/unflag all Electrodes';
            app.importMapButton = uibutton(app.MainTab, 'push');
            app.importMapButton.Enable = 'off';
            app.importMapButton.Text = 'import Map';
            app.DirectoryLabel = uilabel(app.MainTab);
            app.DirectoryLabel.Text = 'Directory: ';
            app.LoginButton = uibutton(app.MainTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Log in ';
            app.SelectDirectoryButton = uibutton(app.MainTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.resetMapButton = uibutton(app.MainTab, 'push');
            app.resetMapButton.ButtonPushedFcn = createCallbackFcn(app, @resetMapButtonPushed, true);
            app.resetMapButton.Text = 'reset Map';
            app.resetValuesButton = uibutton(app.MainTab, 'push');
            app.resetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @resetValuesButtonPushed, true);
            app.resetValuesButton.Text = 'reset Values';
            app.PickFilesButton = uibutton(app.MainTab, 'push');
            app.PickFilesButton.ButtonPushedFcn = createCallbackFcn(app, @PickFilesButtonPushed, true);
            app.PickFilesButton.Text = 'Pick Files';
            app.PickTableButton = uibutton(app.MainTab, 'push');
            app.PickTableButton.ButtonPushedFcn = createCallbackFcn(app, @PickTableButtonPushed, true);
            app.PickTableButton.Text = 'Pick Table';
            app.GenBurstTab = uitab(app.OversightTabGroup);
            app.GenBurstTab.Title = 'Gen. Burst';
            app.Burst_Example = uiaxes(app.GenBurstTab);
            title(app.Burst_Example, 'Zoomed view')
            xlabel(app.Burst_Example, 'Time [ms]')
            ylabel(app.Burst_Example, 'Amplitude [CL]')
            app.SaveAsButton = uibutton(app.GenBurstTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Click "Graph" before saving to generate the correct .mat files'};
            app.SaveAsButton.Text = 'Save As:';
            app.FilenameEditField = uieditfield(app.GenBurstTab, 'text');
            app.FilenameEditField.Value = 'Insert_Filename';
            app.InputgapsButton = uibutton(app.GenBurstTab, 'push');
            app.InputgapsButton.ButtonPushedFcn = createCallbackFcn(app, @InputgapsButtonPushed, true);
            app.InputgapsButton.Enable = 'off';
            app.InputgapsButton.Text = 'Input gaps';
            app.BurstperiodppsLabel = uilabel(app.GenBurstTab);
            app.BurstperiodppsLabel.Text = 'Rate [pps]';
            app.RateEditField_inpps = uieditfield(app.GenBurstTab, 'numeric');
            app.RateEditField_inpps.Limits = [0 15432.0988];
            app.RateEditField_inpps.ValueDisplayFormat = '%.0f';
            app.RateEditField_inpps.ValueChangedFcn = createCallbackFcn(app, @RateEditField_inppsValueChanged, true);
            app.RateEditField_inpps.Tooltip = {'Enter burst period in ms'};
            app.RateEditField_inpps.Value = 900;
            app.InterpulsegapmsLabel = uilabel(app.GenBurstTab);
            app.InterpulsegapmsLabel.Text = 'Interpulsegap [ms]';
            app.PulsesperFrameLabel = uilabel(app.GenBurstTab);
            app.PulsesperFrameLabel.Text = 'Pulses per Burst';
            app.PulsesperburstEditField = uieditfield(app.GenBurstTab, 'numeric');
            app.PulsesperburstEditField.Limits = [1 Inf];
            app.PulsesperburstEditField.RoundFractionalValues = 'on';
            app.PulsesperburstEditField.ValueDisplayFormat = '%.0f';
            app.PulsesperburstEditField.ValueChangedFcn = createCallbackFcn(app, @PulsesperburstEditFieldValueChanged, true);
            app.PulsesperburstEditField.Tooltip = {'Enter number of pulses within a burst. '; 'NOTE: If only 1 pulse is entered, interpulsegap will be ignored'};
            app.PulsesperburstEditField.Value = 1;
            app.InterpulsegapEditField = uieditfield(app.GenBurstTab, 'numeric');
            app.InterpulsegapEditField.Limits = [0 Inf];
            app.InterpulsegapEditField.ValueDisplayFormat = '%.4f';
            app.InterpulsegapEditField.ValueChangedFcn = createCallbackFcn(app, @InterpulsegapEditFieldValueChanged, true);
            app.InterpulsegapEditField.Enable = 'off';
            app.InterpulsegapEditField.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.InterpulsegapEditField.Value = 0.0648;
            app.BurstGapmsLabel = uilabel(app.GenBurstTab);
            app.BurstGapmsLabel.Text = 'Wait-Period [ms]';
            app.WaitPeriodEditField = uieditfield(app.GenBurstTab, 'numeric');
            app.WaitPeriodEditField.Limits = [0 Inf];
            app.WaitPeriodEditField.ValueDisplayFormat = '%.4f ';
            app.WaitPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @WaitPeriodEditFieldValueChanged, true);
            app.WaitPeriodEditField.Tooltip = {'Enter burst period in ms'};
            app.WaitPeriodEditField.Value = 1.1111;
            app.FrameperiodLabel = uilabel(app.GenBurstTab);
            app.FrameperiodLabel.Text = 'Frame-period: ';
            app.BurstperiodppsLabel_2 = uilabel(app.GenBurstTab);
            app.BurstperiodppsLabel_2.Text = 'Rate [ms]';
            app.RateEditField_inms = uieditfield(app.GenBurstTab, 'numeric');
            app.RateEditField_inms.Limits = [0.0666 Inf];
            app.RateEditField_inms.ValueDisplayFormat = '%.4f';
            app.RateEditField_inms.ValueChangedFcn = createCallbackFcn(app, @RateEditField_inmsValueChanged, true);
            app.RateEditField_inms.Tooltip = {'Enter burst period in ms'};
            app.RateEditField_inms.Value = 1.1111;
            app.MaxPulsesLabel = uilabel(app.GenBurstTab);
            app.MaxPulsesLabel.Text = 'Max Pulses: ';
            app.IPGDisplay = uilabel(app.GenBurstTab);
            app.IPGDisplay.Text = '';
            app.IPGInfo_1 = uilabel(app.GenBurstTab);
            app.IPGInfo_1.Text = 'Make sure to use multiples of 2 for the generation of the IPGs as uneven numbers are bad for moving the IPGs later';
            app.IPGInfo_2 = uilabel(app.GenBurstTab);
            app.IPGInfo_2.Text = 'The IPG is the actual interpulsegap + 1 pulse (being one frame). same with Wait-Period.';
            app.ZoomfactorSpinnerLabel = uilabel(app.GenBurstTab);
            app.ZoomfactorSpinnerLabel.Text = 'Zoomfactor';
            app.ZoomfactorSpinner = uispinner(app.GenBurstTab);
            app.ZoomfactorSpinner.Limits = [0 Inf];
            app.ZoomfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ZoomfactorSpinnerValueChanged, true);
            app.ZoomfactorSpinner.Value = 4;
            app.DataAnalysisTab = uitab(app.OversightTabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.giveinputdataButton = uibutton(app.DataAnalysisTab, 'push');
            app.giveinputdataButton.Text = 'give input data';
            app.PatientNameEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.FileorFoldertouseEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.FileorFoldertouseEditFieldLabel.Text = 'File or Folder to use';
            app.FileorFoldertouseEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel = uilabel(app.DataAnalysisTab);
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel.Text = 'usually directory like normally + dis or ranking log folder ';
            app.TabGroup = uitabgroup(app.DataAnalysisTab);
            app.DiscriminationPlotTab = uitab(app.TabGroup);
            app.DiscriminationPlotTab.Title = 'Discrimination Plot ';
            app.RankingPlotTab = uitab(app.TabGroup);
            app.RankingPlotTab.Title = 'Ranking Plot';
            app.UIAxes2 = uiaxes(app.RankingPlotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.createPlotButton = uibutton(app.RankingPlotTab, 'push');
            app.createPlotButton.Text = 'create Plot';
            app.nameofLogorderorsoLabel = uilabel(app.RankingPlotTab);
            app.nameofLogorderorsoLabel.Text = 'name of Log order or so ';
            app.needautomatedlognamingLabel = uilabel(app.RankingPlotTab);
            app.needautomatedlognamingLabel.Text = 'need automated log naming ';
            app.AppSettingsTab = uitab(app.OversightTabGroup);
            app.AppSettingsTab.Title = 'App Settings';
            app.CalculateasDynamicRangeofMapCheckBox = uicheckbox(app.AppSettingsTab);
            app.CalculateasDynamicRangeofMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateasDynamicRangeofMapCheckBoxValueChanged, true);
            app.CalculateasDynamicRangeofMapCheckBox.Text = 'Calculate as Dynamic Range of Map';
            app.CalculateasDynamicRangeofMapCheckBox.Value = true;
            app.RampingCheckBox = uicheckbox(app.AppSettingsTab);
            app.RampingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RampingCheckBoxValueChanged, true);
            app.RampingCheckBox.Text = 'Ramping ';
            app.RampingCheckBox.Value = true;
            app.Decideafter2unanimousDecisionsCheckBox = uicheckbox(app.AppSettingsTab);
            app.Decideafter2unanimousDecisionsCheckBox.Enable = 'off';
            app.Decideafter2unanimousDecisionsCheckBox.Text = {' Decide after 2 unanimous '; ' Decisions'};
            app.Decideafter2unanimousDecisionsCheckBox.Value = true;
            app.PitchRankingListExperimentLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingListExperimentLabel.Text = 'Pitch Ranking List Experiment:';
            app.QuestionRepetitionEditFieldLabel = uilabel(app.AppSettingsTab);
            app.QuestionRepetitionEditFieldLabel.Enable = 'off';
            app.QuestionRepetitionEditFieldLabel.Text = 'Question Repetition ';
            app.QuestionRepetitionEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.QuestionRepetitionEditField.Limits = [0 Inf];
            app.QuestionRepetitionEditField.RoundFractionalValues = 'on';
            app.QuestionRepetitionEditField.Enable = 'off';
            app.QuestionRepetitionEditField.Value = 6;
            app.StimphasesEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimphasesEditFieldLabel.Enable = 'off';
            app.StimphasesEditFieldLabel.Text = 'Stim phase [µs]';
            app.StimphaseEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.StimphaseEditField.Limits = [0 Inf];
            app.StimphaseEditField.ValueDisplayFormat = '%.0f';
            app.StimphaseEditField.Enable = 'off';
            app.StimphaseEditField.Tooltip = {'Enter phase width in µs'};
            app.StimphaseEditField.Value = 25;
            app.InterphasegapsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.InterphasegapsEditFieldLabel.Enable = 'off';
            app.InterphasegapsEditFieldLabel.Text = 'Interphase gap [µs]';
            app.InterphasegapsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.InterphasegapsEditField.Limits = [0 Inf];
            app.InterphasegapsEditField.ValueDisplayFormat = '%.0f';
            app.InterphasegapsEditField.Enable = 'off';
            app.InterphasegapsEditField.Tooltip = {'Enter interphase gap in µs'};
            app.InterphasegapsEditField.Value = 7;
            app.StimdurationmsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimdurationmsEditFieldLabel.Text = 'Stim duration [ms]';
            app.Stimduration_inmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.Stimduration_inmsEditField.Limits = [0 Inf];
            app.Stimduration_inmsEditField.Tooltip = {'Enter stim duration in ms'};
            app.Stimduration_inmsEditField.Value = 500;
            app.TransfertoNICCheckBox = uicheckbox(app.AppSettingsTab);
            app.TransfertoNICCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransfertoNICCheckBoxValueChanged, true);
            app.TransfertoNICCheckBox.Text = 'Transfer to NIC';
            app.TransfertoNICCheckBox.Value = true;
            app.ClevelsPulsewidthEditFieldLabel = uilabel(app.AppSettingsTab);
            app.ClevelsPulsewidthEditFieldLabel.Enable = 'off';
            app.ClevelsPulsewidthEditFieldLabel.Text = 'C levels Pulse width';
            app.ClevelsPulsewidthEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.ClevelsPulsewidthEditField.Enable = 'off';
            app.ClevelsPulsewidthEditField.Value = 25;
            app.IntersequencemsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.IntersequencemsEditFieldLabel.Text = 'Intersequence [ms]';
            app.Intersequence_inmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.Intersequence_inmsEditField.Value = 500;
            app.ModeDropDown_2Label = uilabel(app.AppSettingsTab);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AppSettingsTab);
            app.ModeDropDown.Items = {'MP1', 'MP2', 'MP1+2'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'MP1+2';
            app.GenerateLogCheckBox = uicheckbox(app.AppSettingsTab);
            app.GenerateLogCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateLogCheckBoxValueChanged, true);
            app.GenerateLogCheckBox.Text = 'Generate Log';
            app.GenerateLogCheckBox.Value = true;
            app.OverallLabel = uilabel(app.AppSettingsTab);
            app.OverallLabel.Text = 'Overall';
            app.SequenceGenerationLabel = uilabel(app.AppSettingsTab);
            app.SequenceGenerationLabel.Text = 'Sequence Generation';
            app.BuildframerateLabel = uilabel(app.AppSettingsTab);
            app.BuildframerateLabel.Text = 'Build framerate';
            app.AllTokenOrdersCheckBox = uicheckbox(app.AppSettingsTab);
            app.AllTokenOrdersCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllTokenOrdersCheckBoxValueChanged, true);
            app.AllTokenOrdersCheckBox.Text = ' All Token Orders';
            app.TokenPatternDropDownLabel = uilabel(app.AppSettingsTab);
            app.TokenPatternDropDownLabel.Text = 'Token Pattern ';
            app.TokenPatternDropDown = uidropdown(app.AppSettingsTab);
            app.TokenPatternDropDown.Items = {'Odd Token Out', '2 Alternate Out', '4I2AFC'};
            app.TokenPatternDropDown.ValueChangedFcn = createCallbackFcn(app, @TokenPatternDropDownValueChanged, true);
            app.TokenPatternDropDown.Value = 'Odd Token Out';
            app.TokenOrderDropDownLabel = uilabel(app.AppSettingsTab);
            app.TokenOrderDropDownLabel.Text = 'Token Order';
            app.TokenOrderDropDown = uidropdown(app.AppSettingsTab);
            app.TokenOrderDropDown.Items = {'A - B - A', 'B - A - A', 'A - A - B'};
            app.TokenOrderDropDown.Value = 'A - B - A';
            app.InterpulsegapmsLabel_4 = uilabel(app.AppSettingsTab);
            app.InterpulsegapmsLabel_4.Text = 'Initiate Electrodes';
            app.Initiate_Electrodes = uieditfield(app.AppSettingsTab, 'text');
            app.Initiate_Electrodes.CharacterLimits = [1 22];
            app.Initiate_Electrodes.InputType = 'digits';
            app.Initiate_Electrodes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.Initiate_Electrodes.Value = '1';
            app.Initiate_Amplitudes = uieditfield(app.AppSettingsTab, 'numeric');
            app.Initiate_Amplitudes.Limits = [0 1];
            app.Initiate_Amplitudes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.Initiate_Amplitudes.Value = 0.8;
            app.InterpulsegapmsLabel_5 = uilabel(app.AppSettingsTab);
            app.InterpulsegapmsLabel_5.Text = 'Initiate Amplitudes';
            app.RampingLength_inmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.RampingLength_inmsEditField.Limits = [0 200];
            app.RampingLength_inmsEditField.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.RampingLength_inmsEditField.Value = 10;
            app.InterpulsegapmsLabel_6 = uilabel(app.AppSettingsTab);
            app.InterpulsegapmsLabel_6.Text = 'Ramping length [ms]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tigerv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end